classdef cv_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        DashboardTab                    matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout10                    matlab.ui.container.GridLayout
        Panel_7                         matlab.ui.container.Panel
        GridLayout16_6                  matlab.ui.container.GridLayout
        GeologicalLocationLabel         matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        GridLayout16_5                  matlab.ui.container.GridLayout
        GridLayout20_2                  matlab.ui.container.GridLayout
        EndImgFilenameLabel             matlab.ui.control.Label
        EndImageLabel                   matlab.ui.control.Label
        GridLayoutEndImage              matlab.ui.container.GridLayout
        Panel_5                         matlab.ui.container.Panel
        GridLayout16_4                  matlab.ui.container.GridLayout
        GridLayout20                    matlab.ui.container.GridLayout
        StartImgFilenameLabel           matlab.ui.control.Label
        StartImageLabel                 matlab.ui.control.Label
        GridLayoutStartImage            matlab.ui.container.GridLayout
        GridLayout8                     matlab.ui.container.GridLayout
        Panel_2                         matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        GridLayout19                    matlab.ui.container.GridLayout
        PreviewFilenameLabel            matlab.ui.control.Label
        ImagePreviewLabel               matlab.ui.control.Label
        GridLayoutImagePreview          matlab.ui.container.GridLayout
        UIAxesImagePreview              matlab.ui.control.UIAxes
        GridLayout9                     matlab.ui.container.GridLayout
        Panel_4                         matlab.ui.container.Panel
        GridLayout16_3                  matlab.ui.container.GridLayout
        GridLayout18                    matlab.ui.container.GridLayout
        QuickSelectSlider               matlab.ui.control.Slider
        QuickSelectSliderLabel          matlab.ui.control.Label
        PreviewModeDropDown             matlab.ui.control.DropDown
        PreviewModeDropDownLabel        matlab.ui.control.Label
        ImageSelectDropDown             matlab.ui.control.DropDown
        ImageSelectDropDownLabel        matlab.ui.control.Label
        ImagePreviewSettingLabel        matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        GridLayout16_2                  matlab.ui.container.GridLayout
        GridLayout22                    matlab.ui.container.GridLayout
        ImageSetNameLabel               matlab.ui.control.Label
        ImageSetNameTagLabel            matlab.ui.control.Label
        ImageSetInformationLabel        matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        GridLayout7_4                   matlab.ui.container.GridLayout
        ImageSizeLabel                  matlab.ui.control.Label
        CroppedImageSizeLabel           matlab.ui.control.Label
        GridLayout7_3                   matlab.ui.container.GridLayout
        MonthLabel                      matlab.ui.control.Label
        TimeBetweenImagesLabel          matlab.ui.control.Label
        GridLayout7_2                   matlab.ui.container.GridLayout
        YearsLabel                      matlab.ui.control.Label
        TimespanLabel                   matlab.ui.control.Label
        GridLayout7                     matlab.ui.container.GridLayout
        ImagesLabel                     matlab.ui.control.Label
        ImageLoadedLabel                matlab.ui.control.Label
        SliderViewTab                   matlab.ui.container.Tab
        GridLayout4_2                   matlab.ui.container.GridLayout
        GridLayout8_2                   matlab.ui.container.GridLayout
        Panel_40                        matlab.ui.container.Panel
        GridLayout23                    matlab.ui.container.GridLayout
        GridLayout27                    matlab.ui.container.GridLayout
        SliderInstructionCard           matlab.ui.control.Label
        Slider_SaveGIFButton            matlab.ui.control.Button
        Slider_AutoScanButton           matlab.ui.control.Button
        SliderPositionCardLayout        matlab.ui.container.GridLayout
        Slider_ResetRightButton         matlab.ui.control.Button
        Slider_ResetCenterButton        matlab.ui.control.Button
        Slider_ResetLeftButton          matlab.ui.control.Button
        GridLayout25_3                  matlab.ui.container.GridLayout
        Panel_43                        matlab.ui.container.Panel
        GridLayout25_2                  matlab.ui.container.GridLayout
        Panel_42                        matlab.ui.container.Panel
        GridLayout25                    matlab.ui.container.GridLayout
        Panel_41                        matlab.ui.container.Panel
        GridLayout24                    matlab.ui.container.GridLayout
        Slider_Img2DropDown             matlab.ui.control.DropDown
        DropDown_2Label_2               matlab.ui.control.Label
        Slider_Img1DropDown             matlab.ui.control.DropDown
        Label_3                         matlab.ui.control.Label
        SliderControlsLabel             matlab.ui.control.Label
        Panel_39                        matlab.ui.container.Panel
        SliderMainLayout                matlab.ui.container.GridLayout
        Slider_StatusLabel              matlab.ui.control.Label
        Slider_MainAxes                 matlab.ui.control.UIAxes
        SliderTitleBar                  matlab.ui.container.Panel
        SliderTitleLayout               matlab.ui.container.GridLayout
        SliderMainTitle                 matlab.ui.control.Label
        ClickanddragtocontrolcurtainpositionLabel  matlab.ui.control.Label
        DifferenceHighlightTab          matlab.ui.container.Tab
        GridLayout4_3                   matlab.ui.container.GridLayout
        GridLayout10_8                  matlab.ui.container.GridLayout
        Panel_48                        matlab.ui.container.Panel
        GridLayout16_9                  matlab.ui.container.GridLayout
        GridLayout32                    matlab.ui.container.GridLayout
        DiffHighlight_ResetButton       matlab.ui.control.Button
        SaveAnalysisResultsButton       matlab.ui.control.Button
        OperationToolsLabel             matlab.ui.control.Label
        Panel_47                        matlab.ui.container.Panel
        GridLayout16_8                  matlab.ui.container.GridLayout
        GridLayout20_4                  matlab.ui.container.GridLayout
        DiffHighlight_RightImage_Label  matlab.ui.control.Label
        SecondImageLabel                matlab.ui.control.Label
        DiffHighlight_RightImage_Grid   matlab.ui.container.GridLayout
        Panel_46                        matlab.ui.container.Panel
        GridLayout16_7                  matlab.ui.container.GridLayout
        GridLayout20_3                  matlab.ui.container.GridLayout
        DiffHighlight_LeftImage_Label   matlab.ui.control.Label
        FirstImageLabel                 matlab.ui.control.Label
        DiffHighlight_LeftImage_Grid    matlab.ui.container.GridLayout
        GridLayout4_8                   matlab.ui.container.GridLayout
        Panel_45                        matlab.ui.container.Panel
        GridLayout28                    matlab.ui.container.GridLayout
        DiffDetailButtonLayout          matlab.ui.container.GridLayout
        DiffHighlight_IncreaseOnlyButton  matlab.ui.control.Button
        DiffHighlight_DecreaseOnlyButton  matlab.ui.control.Button
        DiffHighlight_HighlightToggle   matlab.ui.control.StateButton
        analysisFuncLabel               matlab.ui.control.Label
        DiffHighlight_ThresholdLabel    matlab.ui.control.Label
        DiffHighlight_ThresholdSlider   matlab.ui.control.Slider
        thresholdLabel                  matlab.ui.control.Label
        GridLayout29                    matlab.ui.container.GridLayout
        DiffHighlight_Img1DropDown      matlab.ui.control.DropDown
        Label_4                         matlab.ui.control.Label
        DiffHighlight_Img2DropDown      matlab.ui.control.DropDown
        Label_5                         matlab.ui.control.Label
        imgSelectionLabel               matlab.ui.control.Label
        DiffHighlight_StatusLabel       matlab.ui.control.Label
        Panel_44                        matlab.ui.container.Panel
        DiffHighlightMainLayout         matlab.ui.container.GridLayout
        DiffHighlight_Analysis_Axes     matlab.ui.control.UIAxes
        HeatmapTab                      matlab.ui.container.Tab
        GridLayout4_11                  matlab.ui.container.GridLayout
        GridLayout10_10                 matlab.ui.container.GridLayout
        Panel_58                        matlab.ui.container.Panel
        GridLayout16_15                 matlab.ui.container.GridLayout
        GridLayout32_3                  matlab.ui.container.GridLayout
        DiffHighlight_ResetButton_3     matlab.ui.control.Button
        SaveAnalysisResultsButton_3     matlab.ui.control.Button
        OperationToolsLabel_3           matlab.ui.control.Label
        Panel_57                        matlab.ui.container.Panel
        GridLayout16_14                 matlab.ui.container.GridLayout
        GridLayout20_8                  matlab.ui.container.GridLayout
        EndImgFilenameLabel_4           matlab.ui.control.Label
        SecondImageLabel_3              matlab.ui.control.Label
        DiffHighlight_RightImage_Cropped_3  matlab.ui.container.GridLayout
        Panel_56                        matlab.ui.container.Panel
        GridLayout16_13                 matlab.ui.container.GridLayout
        GridLayout20_7                  matlab.ui.container.GridLayout
        StartImgFilenameLabel_4         matlab.ui.control.Label
        FirstImageLabel_3               matlab.ui.control.Label
        DiffHighlight_LeftImage_Cropped_3  matlab.ui.container.GridLayout
        GridLayout4_12                  matlab.ui.container.GridLayout
        Panel_55                        matlab.ui.container.Panel
        GridLayout28_3                  matlab.ui.container.GridLayout
        DiffDetailButtonLayout_3        matlab.ui.container.GridLayout
        DiffHighlight_IncreaseOnlyButton_3  matlab.ui.control.Button
        DiffHighlight_DecreaseOnlyButton_3  matlab.ui.control.Button
        DiffHighlight_HighlightToggle_3  matlab.ui.control.Button
        analysisFuncLabel_3             matlab.ui.control.Label
        DiffHighlight_ThresholdLabel_3  matlab.ui.control.Label
        DiffHighlight_ThresholdSlider_3  matlab.ui.control.Slider
        thresholdLabel_3                matlab.ui.control.Label
        GridLayout29_3                  matlab.ui.container.GridLayout
        DiffHighlight_Img1DropDown_3    matlab.ui.control.DropDown
        Label_9                         matlab.ui.control.Label
        DiffHighlight_Img2DropDown_3    matlab.ui.control.DropDown
        Label_8                         matlab.ui.control.Label
        imgSelectionLabel_3             matlab.ui.control.Label
        DiffHighlight_StatusLabel_3     matlab.ui.control.Label
        Panel_54                        matlab.ui.container.Panel
        DiffHighlightMainLayout_3       matlab.ui.container.GridLayout
        DiffHighlight_Analysis_Axes_3   matlab.ui.control.UIAxes
        TimelapseTab                    matlab.ui.container.Tab
        GridLayout4_9                   matlab.ui.container.GridLayout
        GridLayout10_9                  matlab.ui.container.GridLayout
        Panel_53                        matlab.ui.container.Panel
        GridLayout16_12                 matlab.ui.container.GridLayout
        GridLayout32_2                  matlab.ui.container.GridLayout
        DiffHighlight_ResetButton_2     matlab.ui.control.Button
        SaveAnalysisResultsButton_2     matlab.ui.control.Button
        OperationToolsLabel_2           matlab.ui.control.Label
        Panel_52                        matlab.ui.container.Panel
        GridLayout16_11                 matlab.ui.container.GridLayout
        GridLayout20_6                  matlab.ui.container.GridLayout
        EndImgFilenameLabel_3           matlab.ui.control.Label
        SecondImageLabel_2              matlab.ui.control.Label
        DiffHighlight_RightImage_Cropped_2  matlab.ui.container.GridLayout
        Panel_51                        matlab.ui.container.Panel
        GridLayout16_10                 matlab.ui.container.GridLayout
        GridLayout20_5                  matlab.ui.container.GridLayout
        StartImgFilenameLabel_3         matlab.ui.control.Label
        FirstImageLabel_2               matlab.ui.control.Label
        DiffHighlight_LeftImage_Cropped_2  matlab.ui.container.GridLayout
        GridLayout4_10                  matlab.ui.container.GridLayout
        Panel_50                        matlab.ui.container.Panel
        GridLayout28_2                  matlab.ui.container.GridLayout
        DiffDetailButtonLayout_2        matlab.ui.container.GridLayout
        DiffHighlight_IncreaseOnlyButton_2  matlab.ui.control.Button
        DiffHighlight_DecreaseOnlyButton_2  matlab.ui.control.Button
        DiffHighlight_HighlightToggle_2  matlab.ui.control.Button
        analysisFuncLabel_2             matlab.ui.control.Label
        DiffHighlight_ThresholdLabel_2  matlab.ui.control.Label
        DiffHighlight_ThresholdSlider_2  matlab.ui.control.Slider
        thresholdLabel_2                matlab.ui.control.Label
        GridLayout29_2                  matlab.ui.container.GridLayout
        DiffHighlight_Img1DropDown_2    matlab.ui.control.DropDown
        Label_7                         matlab.ui.control.Label
        DiffHighlight_Img2DropDown_2    matlab.ui.control.DropDown
        Label_6                         matlab.ui.control.Label
        imgSelectionLabel_2             matlab.ui.control.Label
        DiffHighlight_StatusLabel_2     matlab.ui.control.Label
        Panel_49                        matlab.ui.container.Panel
        DiffHighlightMainLayout_2       matlab.ui.container.GridLayout
        DiffHighlight_Analysis_Axes_2   matlab.ui.control.UIAxes
        FlickerViewTab                  matlab.ui.container.Tab
        GridLayout4_5                   matlab.ui.container.GridLayout
        GridLayout10_5                  matlab.ui.container.GridLayout
        Panel_30                        matlab.ui.container.Panel
        Panel_29                        matlab.ui.container.Panel
        GridLayout8_5                   matlab.ui.container.GridLayout
        Panel_27                        matlab.ui.container.Panel
        GridLayout9_5                   matlab.ui.container.GridLayout
        Panel_28                        matlab.ui.container.Panel
        SemanticSegmentationTab         matlab.ui.container.Tab
        GridLayout4_6                   matlab.ui.container.GridLayout
        GridLayout10_6                  matlab.ui.container.GridLayout
        Panel_34                        matlab.ui.container.Panel
        Panel_33                        matlab.ui.container.Panel
        GridLayout8_6                   matlab.ui.container.GridLayout
        Panel_31                        matlab.ui.container.Panel
        GridLayout9_6                   matlab.ui.container.GridLayout
        Panel_32                        matlab.ui.container.Panel
        ReportTab                       matlab.ui.container.Tab
        GridLayout4_7                   matlab.ui.container.GridLayout
        GridLayout10_7                  matlab.ui.container.GridLayout
        Panel_38                        matlab.ui.container.Panel
        Panel_37                        matlab.ui.container.Panel
        GridLayout8_7                   matlab.ui.container.GridLayout
        Panel_35                        matlab.ui.container.Panel
        GridLayout9_7                   matlab.ui.container.GridLayout
        Panel_36                        matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        EarthViewerGUITeam25Label       matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        GridLayout13                    matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        OverviewTab                     matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        GridLayout21                    matlab.ui.container.GridLayout
        EndImageDropDown                matlab.ui.control.DropDown
        DropDown_2Label                 matlab.ui.control.Label
        StartImageDropDown              matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        Step2Label                      matlab.ui.control.Label
        Step1Label                      matlab.ui.control.Label
        SelectInputFilesButton          matlab.ui.control.Button
        FilesTab_2                      matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        FileTree                        matlab.ui.container.CheckBoxTree
        NoFilesLoadedNode               matlab.ui.container.TreeNode
        LoadedFilesLabel                matlab.ui.control.Label
        SettingsTab                     matlab.ui.container.Tab
        GridLayout14_2                  matlab.ui.container.GridLayout
        SettingsLabel                   matlab.ui.control.Label
        GridLayout12                    matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        StartImage matlab.ui.control.Image
        EndImage matlab.ui.control.Image

        DiffHighlightStartImage matlab.ui.control.Image
        DiffHighlightEndImage matlab.ui.control.Image
        
        DashboardDropdownItems string

        PreviewImageData table
        StartImageData table
        EndImageData table
        
        ImageDatasets cell

        AlignedImages cell

        CroppedImages cell

        CurrentLoadedTable table

        DashboardPreviewMode string

        % Slider View Properties
        Slider_SelectedImg1 = 1
        Slider_SelectedImg2 = 2
        Slider_IsDragging = false
        Slider_CurrentCurtainPos = 0
        Slider_IsScanning = false
        Slider_ImageWidth = 0
        Slider_ImageHeight = 0
        Slider_CurrentImg1 = []
        Slider_CurrentImg2 = []
        Slider_MainImageHandle = []

        % Difference Highlight Properties
        DiffHighlight_SelectedImg1 = 1
        DiffHighlight_SelectedImg2 = 2
        DiffHighlight_CurrentThreshold = 0.1
        DiffHighlight_CurrentHighlightImg = []
        DiffHighlight_IsHighlightOn = false
        DiffHighlight_IsIncreaseOnlyOn = false
        DiffHighlight_IsDecreaseOnlyOn = false
        
    end
    
    methods (Access = private)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Backend: Helper and data processing functions
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Load Image: modified from loadImageFolder.m
        function imgTable = loadImageFolder(app)

            % Load and sort all image files from selected folder into a table.
            startingFolder = fullfile(pwd, 'data');
            folder = uigetdir(startingFolder);

            if folder == 0, imgTable = []; return; end
        
            % Supported image extensions
            exts = {'*.jpg', '*.jpeg', '*.png', '*.bmp'};
            files = [];
            for i = 1:length(exts)
                files = [files; dir(fullfile(folder, exts{i}))]; %#ok<AGROW>
            end
        
            if numel(files) < 2
                errordlg('At least two images are required.', 'Error');
                imgTable = [];
                return;
            end
        
            % Extract timestamps (year and optional month) from filenames
            names = {files.name};
            timestamps = zeros(1, numel(names));
            for i = 1:numel(names)
                tokens = regexp(names{i}, '(\d{4})[_\-\.]?(\d{2})', 'tokens', 'once');
                if ~isempty(tokens)
                    year = str2double(tokens{1});
                    month = str2double(tokens{2});
                    timestamps(i) = year * 100 + month;
                else
                    yearOnly = regexp(names{i}, '\d{4}', 'match', 'once');
                    if ~isempty(yearOnly)
                        year = str2double(yearOnly);
                        timestamps(i) = year * 100;
                    else
                        timestamps(i) = Inf; % invalid or no date info
                    end
                end
            end
        
            % Sort by timestamp (ascending)
            [~, sortedIdx] = sort(timestamps);
            sortedNames = names(sortedIdx);
            sortedFiles = files(sortedIdx);
            sortedTimestamps = timestamps(sortedIdx);
            
            % Add folder name as dataset name to distinguish between datasets
            [~, folderName, ~] = fileparts(folder);
            fullNames = string(folderName) + " " + string(sortedNames(:));

            % Build output table
            paths = fullfile(folder, {sortedFiles.name}');
            imgTable = table(fullNames, paths(:), sortedTimestamps(:),'VariableNames', {'Name', 'Path', 'Timestamp'});
            app.CurrentLoadedTable = imgTable;
            app.CurrentLoadedTable.Properties.UserData = folderName;
        end
        
        % Align and crop image series: modified from martinTest.m
        function preproccessImages(app)
            numImages = height(app.CurrentLoadedTable);
            imgs = cell(1, numImages);
            for i = 1:numImages
                imgs{i} = im2double(imread(app.CurrentLoadedTable.Path{i}));
            end

            % ⇨ Bilder alignen
            [alignedImgs, tforms] = preprocessImageSequence(imgs);
            app.AlignedImages = alignedImgs;

            % ⇨ Gemeinsamer Ausschnitt (automatisch zugeschnitten)
            croppedImgs = cropToCommonRegion(alignedImgs);
            app.CroppedImages = croppedImgs;
            
            % Update Stat Cards
            [imgHeight, imgWidth, ~] = size(croppedImgs{1});
            app.ImageSizeLabel.Text = string(imgWidth) + " x " + string(imgHeight);
        end
        
        % Controller for which image is selected on dashboard
        function img = imageLoadController(app, imgInfo)
            if strcmp(app.DashboardPreviewMode, "Aligned")
                idx = find(strcmp(app.CurrentLoadedTable.Name, imgInfo.Name{1}), 1);
                if ~isempty(idx)
                    img = app.AlignedImages{idx};
                end
            elseif strcmp(app.DashboardPreviewMode, "Cropped")
                idx = find(strcmp(app.CurrentLoadedTable.Name, imgInfo.Name{1}), 1);
                if ~isempty(idx)
                    img = app.CroppedImages{idx};
                end
            else
                img = im2double(imread(imgInfo.Path{1}));
            end 
        end
        
        % Dataset manager
        function addDataset(app,dataset)
            % Extract dataset name from folder
            datasetName = app.getDatasetName(dataset);
            
            % Check for duplicate datasets by name
            if isempty(app.ImageDatasets)
                existingNames = {};
            else
                existingNames = cellfun(@(d) d.Name, app.ImageDatasets, 'UniformOutput', false);
            end
        
            if any(strcmp(existingNames, datasetName))
                warning("Dataset '%s' already loaded.", datasetName);
            else
                % Store dataset as struct inside ImageDatasets
                newDataset = struct('Name', datasetName, 'Table', dataset);
                app.ImageDatasets{end+1} = newDataset; 
            end
        end
        
        % Get dataset name from path 
        function datasetName = getDatasetName(~,dataset)
            firstImagePath = dataset.Path{1};
            [folderPath, ~, ~] = fileparts(firstImagePath);
            [~, datasetName] = fileparts(folderPath);
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Main GUI update function
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Display dataset on GUI
        function displayDataset(app, dataset)
            firstImage = dataset(1, :);
            lastImage = dataset(end, :);

            % PreviewImage UI Load (Interactive)
            app.PreviewImageData = firstImage;
            app.updatePreview();
            
            % StartImage UI Load
            app.StartImageData = firstImage;
            app.updateStart();

            % EndImage UI Load
            app.EndImageData = lastImage;
            app.updateEnd();   

            % Update Dropdown
            app.DashboardDropdownItems = dataset.Name;
            app.updateDropdown();
            
            % Update Sidebar
            app.updateStats();
            app.updateSlider();
            
            % Update Dashboard Elements
            app.updateFileTree();
            app.updateSidebarDropdown();
            app.updateImageSetInformation();
            
            % Update Slider View
            app.updateSliderDropdowns();

            app.updateDiffHighlightDropdowns();
        end

        % Refresh processed data after processing ends
        function updateDataset(app)
            app.updatePreview();
            app.updateSliderDropdowns();
            app.updateDifferenceHighlight();
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % 0. Side Bar Functions
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Sidebar dataset start and end select
        function updateDatasetFromSidebarSelection(app)
            % Selected start and end names
            startName = app.StartImageDropDown.Value;
            endName = app.EndImageDropDown.Value;
        
            % Locate dataset
            datasetName = app.getDatasetName(app.CurrentLoadedTable);
            dataset = app.ImageDatasets{strcmp(datasetName, cellfun(@(d) d.Name, app.ImageDatasets, 'UniformOutput', false))};
            allNames = dataset.Table.Name;
        
            % Find indices
            idxStart = find(strcmp(allNames, startName), 1);
            idxEnd = find(strcmp(allNames, endName), 1);
            if isempty(idxStart) || isempty(idxEnd) || idxEnd < idxStart, return; end
        
            % Update only if start or end differs
            currentNames = app.CurrentLoadedTable.Name;
            if ~strcmp(currentNames{1}, startName) || ~strcmp(currentNames{end}, endName)
                app.CurrentLoadedTable = dataset.Table(idxStart:idxEnd, :);
                app.CurrentLoadedTable.Properties.UserData = datasetName;
                app.preproccessImages();
                app.displayDataset(app.CurrentLoadedTable);
            end
        end
        
        % Sidebar file tree
        function updateFileTree(app)
            % Clear existing nodes
            app.FileTree.CheckedNodes = [];
            delete(app.FileTree.Children);
        
            % Initialize
            checkedNodes = app.FileTree.empty;
            nodeMap = containers.Map;
        
            % Build tree and node map
            for i = 1:numel(app.ImageDatasets)
                dataset = app.ImageDatasets{i};
                parentNode = uitreenode(app.FileTree, 'Text', dataset.Name);
                for j = 1:height(dataset.Table)
                    imageName = dataset.Table.Name{j};
                    childNode = uitreenode(parentNode, 'Text', imageName);
                    key = [dataset.Name '|' imageName];
                    nodeMap(key) = childNode;
                end
            end
        
            % Find checked nodes
            for i = 1:height(app.CurrentLoadedTable)
                imageName = app.CurrentLoadedTable.Name{i};
                for d = 1:numel(app.ImageDatasets)
                    dataset = app.ImageDatasets{d};
                    if any(strcmp(dataset.Table.Name, imageName))
                        key = [dataset.Name '|' imageName];
                        if nodeMap.isKey(key)
                            checkedNodes(end+1) = nodeMap(key); %#ok<AGROW>
                        end
                        break;
                    end
                end
            end
        
            % Apply checked nodes
            app.FileTree.CheckedNodes = checkedNodes;
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % 0: Side bar functions
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Dashboard Preview Dropdown
        function updateDropdown(app)
            if isvalid(app.ImageSelectDropDown)
                app.ImageSelectDropDown.Items = cellstr(app.DashboardDropdownItems);
                if ~isempty(app.DashboardDropdownItems)
                    app.ImageSelectDropDown.Value = app.DashboardDropdownItems(1);
                end
            end
        end

        % Sidebar Image Selection Dropdown
        function updateSidebarDropdown(app)
            if isvalid(app.StartImageDropDown)
                % Exclude last item for start dropdown
                startItems = app.DashboardDropdownItems(1:end-1);
                app.StartImageDropDown.Items = cellstr(startItems);
                if ~isempty(startItems)
                    app.StartImageDropDown.Value = startItems(1);
                end
            end
            if isvalid(app.EndImageDropDown)
                % Exclude first item for end dropdown
                endItems = app.DashboardDropdownItems(2:end);
                app.EndImageDropDown.Items = cellstr(endItems);
                if ~isempty(endItems)
                    app.EndImageDropDown.Value = endItems(end);
                end
            end
        end

        % Dashboard Preview Image
        function updatePreview(app)
            axis(app.UIAxesImagePreview, 'image');
            app.UIAxesImagePreview.Toolbar.Visible = 'on';
            previewImage = app.imageLoadController(app.PreviewImageData);
            imshow(previewImage, 'Parent', app.UIAxesImagePreview);
            app.PreviewFilenameLabel.Text = app.PreviewImageData.Name{1};
        end

        % Dashboard Start Image
        function updateStart(app)
            if isempty(app.StartImage) || ~isvalid(app.StartImage)
                app.StartImage = uiimage(app.GridLayoutStartImage); 
                app.StartImage.ScaleMethod = 'fit';
            end
            app.StartImage.ImageSource = imread(app.StartImageData.Path{1});
            app.StartImgFilenameLabel.Text = app.StartImageData.Name{1};
        end
        
        % Dashboard End Image
        function updateEnd(app)
            if isempty(app.EndImage) || ~isvalid(app.EndImage)
                app.EndImage = uiimage(app.GridLayoutEndImage); 
                app.EndImage.ScaleMethod = 'fit';
            end
            app.EndImage.ImageSource = imread(app.EndImageData.Path{1});
            app.EndImgFilenameLabel.Text = app.EndImageData.Name{1};
        end

        % Dashboard Stat Cards
        function updateStats(app)
            numImages = height(app.CurrentLoadedTable);
            app.ImagesLabel.Text = string(numImages) + " Images";
            
            timestamps = app.CurrentLoadedTable.Timestamp;
            % Calculate diffference
            startTimestamp = min(timestamps);
            endTimestamp = max(timestamps);
            
            startYear = floor(startTimestamp / 100);
            startMonth = mod(startTimestamp, 100);
            endYear = floor(endTimestamp / 100);
            endMonth = mod(endTimestamp, 100);
            
            yearsDiff = endYear - startYear;
            monthsDiff = endMonth - startMonth;
            
            totalMonths = yearsDiff * 12 + monthsDiff;
            totalYears = totalMonths / 12;

            app.YearsLabel.Text = string(totalYears) + " Years";
            app.MonthLabel.Text = string(totalMonths / numImages) + " Month";
        end
        
        % Dashboard QuickSelect Slider
        function updateSlider(app)
            numImages = height(app.CurrentLoadedTable);
            app.QuickSelectSlider.Limits = [1, numImages];
            app.QuickSelectSlider.MajorTicks = 1:numImages; 
            app.QuickSelectSlider.MajorTickLabels = string(1:numImages);
            app.QuickSelectSlider.Value = 1;
        end

        % Dashboard Image Set Information Board
        function updateImageSetInformation(app)
            app.ImageSetNameLabel.Text = app.CurrentLoadedTable.Properties.UserData;
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % 1. Slider View Functions
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Update Slider dropdowns based on loaded images
        function updateSliderDropdowns(app)
            try
                if isempty(app.CroppedImages) || isempty(app.CurrentLoadedTable)
                    app.Slider_Img1DropDown.Items = {'🔄 Load images from Dashboard first...'};
                    app.Slider_Img2DropDown.Items = {'🔄 Load images from Dashboard first...'};
                    app.Slider_Img1DropDown.Value = app.Slider_Img1DropDown.Items{1};
                    app.Slider_Img2DropDown.Value = app.Slider_Img2DropDown.Items{1};
                    app.Slider_SelectedImg1 = 0;
                    app.Slider_SelectedImg2 = 0;
                    
                    % Clear display and show instruction
                    app.updateSliderDisplay();
                    app.Slider_StatusLabel.Text = '📂 Please load images from Dashboard tab first';
                    app.Slider_StatusLabel.FontColor = [0.7, 0.4, 0.1];
                    return;
                end
                
                % Use unified image management - get from globally loaded images
                imageNames = app.CurrentLoadedTable.Name;
                app.Slider_Img1DropDown.Items = imageNames;

                app.Slider_Img2DropDown.Items = imageNames;
                
                % Intelligent default selection
                numImages = height(app.CurrentLoadedTable);
                if numImages >= 1
                    app.Slider_Img1DropDown.Value = imageNames{1};
                    app.Slider_SelectedImg1 = 1;
                    
                    % Select the last image as default for comparison if available
                    if numImages >= 2
                        app.Slider_Img2DropDown.Value = imageNames{end};
                        app.Slider_SelectedImg2 = numImages;
                    else
                        app.Slider_Img2DropDown.Value = imageNames{1};
                        app.Slider_SelectedImg2 = 1;
                    end
                else
                    app.Slider_SelectedImg1 = 0;
                    app.Slider_SelectedImg2 = 0;
                end
                
                % Initialize image data using shared processed images
                app.initializeSliderImages();
                
                % Update display with loaded images
                app.updateSliderDisplay();
                app.Slider_StatusLabel.Text = sprintf('✅ %d images ready • Select for comparison', numImages);
                app.Slider_StatusLabel.FontColor = [0, 0.6, 0.2];
                
            catch ME
                app.Slider_StatusLabel.Text = ['❌ Error: ' ME.message];
                app.Slider_StatusLabel.FontColor = [0.8, 0, 0];
                fprintf('Slider dropdown update error: %s\n', ME.message);
            end
        end
        
        % Initialize slider images and dimensions
        function initializeSliderImages(app)
            try
                if app.Slider_SelectedImg1 > 0 && app.Slider_SelectedImg1 <= length(app.CroppedImages)
                    app.Slider_CurrentImg1 = app.CroppedImages{app.Slider_SelectedImg1};
                else
                    app.Slider_CurrentImg1 = [];
                end
                
                if app.Slider_SelectedImg2 > 0 && app.Slider_SelectedImg2 <= length(app.CroppedImages)
                    app.Slider_CurrentImg2 = app.CroppedImages{app.Slider_SelectedImg2};
                else
                    app.Slider_CurrentImg2 = [];
                end
                
                % Normalize image sizes if both images exist
                if ~isempty(app.Slider_CurrentImg1) && ~isempty(app.Slider_CurrentImg2)
                    [app.Slider_CurrentImg1, app.Slider_CurrentImg2] = app.normalizeSliderImageSizes(app.Slider_CurrentImg1, app.Slider_CurrentImg2);
                    app.Slider_ImageWidth = size(app.Slider_CurrentImg1, 2);
                    app.Slider_ImageHeight = size(app.Slider_CurrentImg1, 1);
                    app.Slider_CurrentCurtainPos = round(app.Slider_ImageWidth / 2);
                end
                
            catch ME
                app.Slider_StatusLabel.Text = ['Status: Image initialization error - ' ME.message];
                app.Slider_StatusLabel.FontColor = [0.8, 0, 0];
            end
        end
        
        % Normalize image sizes to ensure consistency
        function [img1_norm, img2_norm] = normalizeSliderImageSizes(~, img1, img2)
            [h1, w1, ~] = size(img1);
            [h2, w2, ~] = size(img2);
            
            if h1 ~= h2 || w1 ~= w2
                % Resize to smaller dimensions to ensure full visibility
                target_h = min(h1, h2);
                target_w = min(w1, w2);
                img1_norm = imresize(img1, [target_h, target_w]);
                img2_norm = imresize(img2, [target_h, target_w]);
            else
                img1_norm = img1;
                img2_norm = img2;
            end
        end
        
        % Update slider display
        function updateSliderDisplay(app)
            try
                % Clear axes first
                cla(app.Slider_MainAxes);
                
                if isempty(app.Slider_CurrentImg1) || isempty(app.Slider_CurrentImg2)
                    title(app.Slider_MainAxes, '🎚️ Curtain Slider Display', 'FontSize', 14, 'FontWeight', 'bold');
                    return;
                end
                
                % Create curtain effect image
                curtainImg = app.createCurtainImage(app.Slider_CurrentImg1, app.Slider_CurrentImg2, app.Slider_CurrentCurtainPos);
                
                % Display the curtain image
                app.Slider_MainImageHandle = imshow(curtainImg, 'Parent', app.Slider_MainAxes);
                title(app.Slider_MainAxes, '🎚️ Curtain Slider Display (Click and drag to control)', 'FontSize', 14, 'FontWeight', 'bold');
                
                % Setup mouse interaction
                app.setupSliderMouseInteraction();
                
            catch ME
                app.Slider_StatusLabel.Text = ['Status: Display error - ' ME.message];
                app.Slider_StatusLabel.FontColor = [0.8, 0, 0];
                title(app.Slider_MainAxes, 'Display Error', 'FontSize', 12, 'Color', [0.8, 0, 0]);
            end
        end
        
        % Create curtain effect image (core algorithm from curtainSliderDisplay.m)
        function curtain_img = createCurtainImage(app, img1, img2, curtain_pos)
            [h, w, c] = size(img1);
            curtain_img = zeros(h, w, c, 'like', img1);
            
            % Ensure curtain position is within valid range
            curtain_pos = max(1, min(w, round(curtain_pos)));
            
            % Left side shows img1, right side shows img2
            if curtain_pos > 1
                curtain_img(:, 1:curtain_pos-1, :) = img1(:, 1:curtain_pos-1, :);
            end
            if curtain_pos <= w
                curtain_img(:, curtain_pos:end, :) = img2(:, curtain_pos:end, :);
            end
            
            % Add red curtain dividing line
            if curtain_pos > 1 && curtain_pos <= w
                curtain_img(:, curtain_pos, 1) = 255;
                curtain_img(:, curtain_pos, 2) = 0;
                curtain_img(:, curtain_pos, 3) = 0;
            end
        end
        
        % Setup mouse interaction for slider
        function setupSliderMouseInteraction(app)
            % Set up mouse event handlers for interactive curtain control
            set(app.UIFigure, 'WindowButtonDownFcn', @(src, evt) app.sliderMouseDown(src, evt));
            set(app.UIFigure, 'WindowButtonMotionFcn', @(src, evt) app.sliderMouseMove(src, evt));
            set(app.UIFigure, 'WindowButtonUpFcn', @(src, evt) app.sliderMouseUp(src, evt));
        end
        
        % Mouse down event handler
        function sliderMouseDown(app, src, evt)
            try
                % Check if click is within slider axes
                currentPoint = get(app.Slider_MainAxes, 'CurrentPoint');
                if app.isPointInSliderAxes(currentPoint)
                    app.Slider_IsDragging = true;
                    curtain_pos = app.convertAxesToImageCoords(currentPoint);
                    app.updateSliderCurtainPosition(curtain_pos);
                end
            catch
                % Ignore errors during mouse interaction
            end
        end
        
        % Mouse move event handler
        function sliderMouseMove(app, src, evt)
            try
                if app.Slider_IsDragging
                    currentPoint = get(app.Slider_MainAxes, 'CurrentPoint');
                    if app.isPointInSliderAxes(currentPoint)
                        curtain_pos = app.convertAxesToImageCoords(currentPoint);
                        app.updateSliderCurtainPosition(curtain_pos);
                    end
                end
            catch
                % Ignore errors during mouse interaction
            end
        end
        
        % Mouse up event handler
        function sliderMouseUp(app, src, evt)
            app.Slider_IsDragging = false;
        end
        
        % Check if point is within slider axes
        function is_valid = isPointInSliderAxes(app, current_point)
            try
                x = current_point(1, 1);
                y = current_point(1, 2);
                
                % Get axes limits
                xlim = get(app.Slider_MainAxes, 'XLim');
                ylim = get(app.Slider_MainAxes, 'YLim');
                
                is_valid = (x >= xlim(1) && x <= xlim(2) && y >= ylim(1) && y <= ylim(2));
            catch
                is_valid = false;
            end
        end
        
        % Convert axes coordinates to image pixel coordinates
        function pixel_x = convertAxesToImageCoords(app, current_point)
            try
                x = current_point(1, 1);
                
                % Get current axes limits
                xlim = get(app.Slider_MainAxes, 'XLim');
                
                % Convert to pixel coordinate (1-based indexing)
                pixel_x = round(((x - xlim(1)) / (xlim(2) - xlim(1))) * app.Slider_ImageWidth + 0.5);
                
                % Clamp to valid range
                pixel_x = max(1, min(app.Slider_ImageWidth, pixel_x));
            catch
                pixel_x = round(app.Slider_ImageWidth / 2);
            end
        end
        
        % Update curtain position
        function updateSliderCurtainPosition(app, curtain_pos)
            try
                app.Slider_CurrentCurtainPos = curtain_pos;
                
                if ~isempty(app.Slider_CurrentImg1) && ~isempty(app.Slider_CurrentImg2)
                    % Create new curtain effect image
                    new_img = app.createCurtainImage(app.Slider_CurrentImg1, app.Slider_CurrentImg2, curtain_pos);
                    
                    % Update image display
                    if ishandle(app.Slider_MainImageHandle)
                        set(app.Slider_MainImageHandle, 'CData', new_img);
                    end
                    
                    drawnow;
                end
            catch ME
                app.Slider_StatusLabel.Text = ['Status: Update error - ' ME.message];
                app.Slider_StatusLabel.FontColor = [0.8, 0, 0];
            end
        end
        
        % Reset curtain position with modern feedback
        function resetSliderPosition(app, pos_type)
            try
                if isempty(app.Slider_CurrentImg1) || isempty(app.Slider_CurrentImg2)
                    app.Slider_StatusLabel.Text = '⚠️ Please select two images first';
                    app.Slider_StatusLabel.FontColor = [0.8, 0.5, 0];
                    return;
                end
                
                switch pos_type
                    case 'left'
                        new_pos = 1; % Fully show left image
                        statusMsg = 'Showing left image';
                    case 'center'
                        new_pos = round(app.Slider_ImageWidth/2); % Center curtain
                        statusMsg = 'Centered view';
                    case 'right'
                        new_pos = app.Slider_ImageWidth; % Fully show right image
                        statusMsg = 'Showing right image';
                    otherwise
                        new_pos = round(app.Slider_ImageWidth/2);
                        statusMsg = 'Centered view';
                end
                
                app.updateSliderCurtainPosition(new_pos);
                app.Slider_StatusLabel.Text = ['🎚️ ' statusMsg];
                app.Slider_StatusLabel.FontColor = [0.2, 0.6, 0.2];
            catch ME
                app.Slider_StatusLabel.Text = ['❌ Reset error: ' ME.message];
                app.Slider_StatusLabel.FontColor = [0.8, 0, 0];
            end
        end
        
        % Auto scan animation
        function performSliderAutoScan(app)
            try
                if app.Slider_IsScanning
                    app.Slider_IsScanning = false;
                    app.Slider_AutoScanButton.Text = '🔄 Auto Scan';
                    app.Slider_StatusLabel.Text = 'Status: Auto scan stopped';
                    app.Slider_StatusLabel.FontColor = [0, 0.4, 0];
                    return;
                end
                
                app.Slider_IsScanning = true;
                app.Slider_AutoScanButton.Text = '⏹️ Stop Scan';
                app.Slider_StatusLabel.Text = 'Status: Auto scanning...';
                app.Slider_StatusLabel.FontColor = [0, 0.4, 0];
                
                step_size = max(1, round(app.Slider_ImageWidth/50));
                
                % Scan from left to right
                for pos = 1:step_size:app.Slider_ImageWidth
                    if ~app.Slider_IsScanning
                        break;
                    end
                    app.updateSliderCurtainPosition(pos);
                    pause(0.05);
                end
                
                % Scan from right to left
                for pos = app.Slider_ImageWidth:-step_size:1
                    if ~app.Slider_IsScanning
                        break;
                    end
                    app.updateSliderCurtainPosition(pos);
                    pause(0.05);
                end
                
                % Reset state
                app.Slider_IsScanning = false;
                app.Slider_AutoScanButton.Text = '🔄 Auto Scan';
                app.Slider_StatusLabel.Text = 'Status: Auto scan completed';
                app.Slider_StatusLabel.FontColor = [0, 0.4, 0];
                
            catch ME
                app.Slider_IsScanning = false;
                app.Slider_AutoScanButton.Text = '🔄 Auto Scan';
                app.Slider_StatusLabel.Text = ['Status: Scan error - ' ME.message];
                app.Slider_StatusLabel.FontColor = [0.8, 0, 0];
            end
        end
        
        % Reset slider interface
        function resetSliderInterface(app)
            try
                % Stop auto scan if running
                if app.Slider_IsScanning
                    app.performSliderAutoScan(); % This will stop the scan
                end
                
                % Reset image selections based on what's available
                if ~isempty(app.CroppedImages) && ~isempty(app.CurrentLoadedTable)
                    % Images are loaded - reset to first two images
                    app.Slider_SelectedImg1 = 1;
                    app.Slider_SelectedImg2 = min(2, height(app.CurrentLoadedTable));
                    
                    if height(app.CurrentLoadedTable) >= 1
                        app.Slider_Img1DropDown.Value = app.CurrentLoadedTable.Name{1};
                    end
                    if height(app.CurrentLoadedTable) >= 2
                        app.Slider_Img2DropDown.Value = app.CurrentLoadedTable.Name{2};
                    end
                    
                    % Reinitialize images and display
                    app.initializeSliderImages();
                    app.updateSliderDisplay();
                else
                    % No images loaded - reset to empty state
                    app.Slider_SelectedImg1 = 0;
                    app.Slider_SelectedImg2 = 0;
                    app.Slider_Img1DropDown.Items = {'Please load images first'};
                    app.Slider_Img2DropDown.Items = {'Please load images first'};
                    app.Slider_Img1DropDown.Value = app.Slider_Img1DropDown.Items{1};
                    app.Slider_Img2DropDown.Value = app.Slider_Img2DropDown.Items{1};
                    
                    % Clear display
                    cla(app.Slider_MainAxes);
                    title(app.Slider_MainAxes, '🎚️ Curtain Slider Display', 'FontSize', 14, 'FontWeight', 'bold');
                end
                
                % Reset cursor state
                app.Slider_IsDragging = false;
                
                % Set appropriate status message
                if ~isempty(app.CroppedImages) && ~isempty(app.CurrentLoadedTable)
                    app.Slider_StatusLabel.Text = 'Status: Interface reset - ready for comparison';
                    app.Slider_StatusLabel.FontColor = [0, 0.4, 0];
                else
                    app.Slider_StatusLabel.Text = 'Status: Interface reset - please load images first';
                    app.Slider_StatusLabel.FontColor = [0.5, 0.5, 0.5];
                end
                
            catch ME
                app.Slider_StatusLabel.Text = ['Status: Reset error - ' ME.message];
                app.Slider_StatusLabel.FontColor = [0.8, 0, 0];
                fprintf('Slider reset error: %s\n', ME.message);
            end
        end

        % Helper function to extract short image name for clean display
        function shortName = extractShortImageName(app, fullName)
            % Extract just the year or short identifier from full name
            if contains(fullName, '(')
                parts = strsplit(fullName, '(');
                shortName = strtrim(parts{1});
            else
                shortName = fullName;
            end
            
            % Limit length for clean display
            if length(shortName) > 15
                shortName = [shortName(1:12) '...'];
            end
        end
        
        % Create auto scan GIF
        function createSliderAutoScanGIF(app, filename)
            try
                delay_time = 0.1;  % GIF frame delay
                step_size = max(1, round(app.Slider_ImageWidth/30));
                colormap_created = false;
                gif_colormap = [];
                
                % Left to right scan frames
                for pos = 1:step_size:app.Slider_ImageWidth
                    app.updateSliderCurtainPosition(pos);
                    
                    % Get current image data
                    current_img = get(app.Slider_MainImageHandle, 'CData');
                    
                    if ~colormap_created
                        % Create first frame and establish color mapping
                        [indexed_img, gif_colormap] = rgb2ind(current_img, 256, 'dither');
                        colormap_created = true;
                        imwrite(indexed_img, gif_colormap, filename, 'gif', ...
                               'Loopcount', inf, 'DelayTime', delay_time);
                    else
                        % Add subsequent frames
                        indexed_img = rgb2ind(current_img, gif_colormap, 'dither');
                        imwrite(indexed_img, gif_colormap, filename, 'gif', ...
                               'WriteMode', 'append', 'DelayTime', delay_time);
                    end
                    pause(0.02);
                end
                
                % Right to left scan frames
                for pos = app.Slider_ImageWidth:-step_size:1
                    app.updateSliderCurtainPosition(pos);
                    
                    current_img = get(app.Slider_MainImageHandle, 'CData');
                    indexed_img = rgb2ind(current_img, gif_colormap, 'dither');
                    imwrite(indexed_img, gif_colormap, filename, 'gif', ...
                           'WriteMode', 'append', 'DelayTime', delay_time);
                    pause(0.02);
                end
                
            catch ME
                error('GIF creation failed: %s', ME.message);
            end
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % DIFFERENCE HIGHLIGHT METHODS
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Update Difference Highlight UI elements
        function updateDiffHighlightDropdowns(app)
            if isempty(app.CurrentLoadedTable)
                return;
            end
            
            % Update dropdown items
            imageNames = app.CurrentLoadedTable.Name;
            app.DiffHighlight_Img1DropDown.Items = imageNames;
            app.DiffHighlight_Img2DropDown.Items = imageNames;
            
            % Set default selections
            if length(imageNames) >= 2
                app.DiffHighlight_Img1DropDown.Value = imageNames{1};
                app.DiffHighlight_Img2DropDown.Value = imageNames{2};
                app.DiffHighlight_SelectedImg1 = 1;
                app.DiffHighlight_SelectedImg2 = 2;ss
            end
        end
        
        function updateDifferenceHighlight(app)
            if ~isempty(app.CroppedImages)
                % Change view mode to difference highlight
                app.DiffHighlight_HighlightToggle.Value = true;
                app.DiffHighlight_IsHighlightOn = true;
                app.DiffHighlight_IsIncreaseOnlyOn = false;
                app.DiffHighlight_IsDecreaseOnlyOn = false;
                app.DiffHighlight_HighlightToggle.Text = '✨ Hide Highlight Differences';
                app.DiffHighlight_HighlightToggle.BackgroundColor = [0.8 0.2 0.2];
                app.showDiffHighlightHighlight();
                
                % Also update selected image preview
                idx1 = app.DiffHighlight_SelectedImg1;
                idx2 = app.DiffHighlight_SelectedImg2;
                
                if idx1 < 1 || idx2 < 1 || idx1 > numel(app.CroppedImages) || idx2 > numel(app.CroppedImages)
                    return;
                end
            
                % Create image components only once
                if isempty(app.DiffHighlightStartImage) || ~isvalid(app.DiffHighlightStartImage)
                    app.DiffHighlightStartImage = uiimage(app.DiffHighlight_LeftImage_Grid);
                    app.DiffHighlightStartImage.ScaleMethod = 'fit';
                end
                if isempty(app.DiffHighlightEndImage) || ~isvalid(app.DiffHighlightEndImage)
                    app.DiffHighlightEndImage = uiimage(app.DiffHighlight_RightImage_Grid);
                    app.DiffHighlightEndImage.ScaleMethod = 'fit';
                end
            
                % Update image content and labels
                app.DiffHighlightStartImage.ImageSource = app.CroppedImages{idx1};
                app.DiffHighlightEndImage.ImageSource = app.CroppedImages{idx2};
                app.DiffHighlight_LeftImage_Label.Text = app.CurrentLoadedTable.Name{idx1};
                app.DiffHighlight_RightImage_Label.Text = app.CurrentLoadedTable.Name{idx2};
            end
        end

        % Update analysis based on current mode
        function updateDiffHighlightAnalysis(app)
            if app.DiffHighlight_IsHighlightOn
                app.showDiffHighlightHighlight();
            elseif app.DiffHighlight_IsIncreaseOnlyOn
                app.showDiffHighlightIncreaseOnly();
            elseif app.DiffHighlight_IsDecreaseOnlyOn
                app.showDiffHighlightDecreaseOnly();
            end
        end
        
        % Show highlight analysis
        function showDiffHighlightHighlight(app)
            if isempty(app.CroppedImages) || app.DiffHighlight_SelectedImg1 == app.DiffHighlight_SelectedImg2
                uialert(app.UIFigure, 'Please load images and select two different images', 'Warning');
                app.DiffHighlight_HighlightToggle.Value = false;
                app.DiffHighlight_IsHighlightOn = false;
                return;
            end
            
            try
                app.updateDiffHighlightStatus('Calculating highlights...');
                
                img1 = app.CroppedImages{app.DiffHighlight_SelectedImg1};
                img2 = app.CroppedImages{app.DiffHighlight_SelectedImg2};
                
                gray1 = rgb2gray(img1);
                gray2 = rgb2gray(img2);
                diff = abs(gray2 - gray1);
                
                increase = gray2 > gray1;
                decrease = gray2 < gray1;
                significantChange = diff > app.DiffHighlight_CurrentThreshold;
                
                highlightImg = zeros(size(img1));
                highlightImg(:,:,1) = double(increase & significantChange);
                highlightImg(:,:,1) = highlightImg(:,:,1) + double(decrease & significantChange);
                highlightImg(:,:,2) = double(decrease & significantChange);
                
                app.DiffHighlight_CurrentHighlightImg = highlightImg;
                

                cla(app.DiffHighlight_Analysis_Axes);
                baseImg = 0.5 * gray1;
                baseImg = cat(3, baseImg, baseImg, baseImg);
                resultImg = baseImg + 0.8 * highlightImg;
                resultImg = min(resultImg, 1);
                imshow(resultImg, 'Parent', app.DiffHighlight_Analysis_Axes);
                axis(app.DiffHighlight_Analysis_Axes, 'image');
                app.DiffHighlight_Analysis_Axes.XLimMode = 'auto';
                app.DiffHighlight_Analysis_Axes.YLimMode = 'auto';
                title(app.DiffHighlight_Analysis_Axes, sprintf('Highlight (Red=Increase, Yellow=Decrease, Threshold: %.2f)', app.DiffHighlight_CurrentThreshold), 'FontSize', 11);
                
                app.updateDiffHighlightStatus('Highlight calculation completed!');
                
            catch ME
                app.updateDiffHighlightStatus('Highlight calculation failed');
                uialert(app.UIFigure, ['Highlight calculation failed: ' ME.message], 'Error');
            end
        end
        
        % Show increase only analysis
        function showDiffHighlightIncreaseOnly(app)
            if isempty(app.CroppedImages) || app.DiffHighlight_SelectedImg1 == app.DiffHighlight_SelectedImg2
                uialert(app.UIFigure, 'Please load images and select two different images', 'Warning');
                return;
            end
            
            try
                app.DiffHighlight_IsHighlightOn = false;
                app.DiffHighlight_IsIncreaseOnlyOn = true;
                app.DiffHighlight_IsDecreaseOnlyOn = false;
                
                app.DiffHighlight_HighlightToggle.Value = false;
                app.DiffHighlight_HighlightToggle.Text = '✨ Show Highlight Differences';
                app.DiffHighlight_HighlightToggle.BackgroundColor = [0.2157 0.4941 0.7216];
                
                img1 = app.CroppedImages{app.DiffHighlight_SelectedImg1};
                img2 = app.CroppedImages{app.DiffHighlight_SelectedImg2};
                
                gray1 = rgb2gray(img1);
                gray2 = rgb2gray(img2);
                diff = abs(gray2 - gray1);
                
                increase = gray2 > gray1;
                significantChange = diff > app.DiffHighlight_CurrentThreshold;
                
                highlightImg = zeros(size(img1));
                highlightImg(:,:,1) = double(increase & significantChange);
                
                axes(app.DiffHighlight_Analysis_Axes);
                cla(app.DiffHighlight_Analysis_Axes);
                baseImg = 0.5 * gray1;
                baseImg = cat(3, baseImg, baseImg, baseImg);
                resultImg = baseImg + 0.8 * highlightImg;
                resultImg = min(resultImg, 1);
                imshow(resultImg, 'Parent', app.DiffHighlight_Analysis_Axes);
                title(app.DiffHighlight_Analysis_Axes, sprintf('Increase Only (Red, Threshold: %.2f)', app.DiffHighlight_CurrentThreshold), 'FontSize', 11);
                
                app.updateDiffHighlightStatus('Increase only analysis completed!');
                
            catch ME
                uialert(app.UIFigure, ['Increase only analysis failed: ' ME.message], 'Error');
            end
        end
        
        % Show decrease only analysis
        function showDiffHighlightDecreaseOnly(app)
            if isempty(app.CroppedImages) || app.DiffHighlight_SelectedImg1 == app.DiffHighlight_SelectedImg2
                uialert(app.UIFigure, 'Please load images and select two different images', 'Warning');
                return;
            end
            
            try
                app.DiffHighlight_IsHighlightOn = false;
                app.DiffHighlight_IsIncreaseOnlyOn = false;
                app.DiffHighlight_IsDecreaseOnlyOn = true;
                
                app.DiffHighlight_HighlightToggle.Value = false;
                app.DiffHighlight_HighlightToggle.Text = '✨ Show Highlight Differences';
                app.DiffHighlight_HighlightToggle.BackgroundColor = [0.2157 0.4941 0.7216];
                
                img1 = app.CroppedImages{app.DiffHighlight_SelectedImg1};
                img2 = app.CroppedImages{app.DiffHighlight_SelectedImg2};
                
                gray1 = rgb2gray(img1);
                gray2 = rgb2gray(img2);
                diff = abs(gray2 - gray1);
                
                decrease = gray2 < gray1;
                significantChange = diff > app.DiffHighlight_CurrentThreshold;
              
                highlightImg = zeros(size(img1));
                highlightImg(:,:,1) = double(decrease & significantChange);
                highlightImg(:,:,2) = double(decrease & significantChange);
                
                axes(app.DiffHighlight_Analysis_Axes);
                cla(app.DiffHighlight_Analysis_Axes);
                baseImg = 0.5 * gray1;
                baseImg = cat(3, baseImg, baseImg, baseImg);
                resultImg = baseImg + 0.8 * highlightImg;
                resultImg = min(resultImg, 1);
                imshow(resultImg, 'Parent', app.DiffHighlight_Analysis_Axes);
                title(app.DiffHighlight_Analysis_Axes, sprintf('Decrease Only (Yellow, Threshold: %.2f)', app.DiffHighlight_CurrentThreshold), 'FontSize', 11);
                
                app.updateDiffHighlightStatus('Decrease only analysis completed!');
                
            catch ME
                uialert(app.UIFigure, ['Decrease only analysis failed: ' ME.message], 'Error');
            end
        end
        
        % Update status display
        function updateDiffHighlightStatus(app, message)
            if isvalid(app.DiffHighlight_StatusLabel)
                app.DiffHighlight_StatusLabel.Text = ['Status: ' message];
                drawnow;
            end
        end
        
        % Reset difference highlight display
        function resetDiffHighlightDisplay(app)
            try                
                % Clear all axes
                if isvalid(app.DiffHighlight_Analysis_Axes), cla(app.DiffHighlight_Analysis_Axes); end
                
                % Reset titles
                if isvalid(app.DiffHighlight_Analysis_Axes), title(app.DiffHighlight_Analysis_Axes, 'Difference Analysis Results', 'FontSize', 13, 'FontWeight', 'bold'); end
                
                % Reset variables
                app.DiffHighlight_CurrentHighlightImg = [];
                app.DiffHighlight_IsHighlightOn = false;
                app.DiffHighlight_IsIncreaseOnlyOn = false;
                app.DiffHighlight_IsDecreaseOnlyOn = false;
                
                % Reset UI controls
                if isvalid(app.DiffHighlight_HighlightToggle)
                    app.DiffHighlight_HighlightToggle.Value = false;
                    app.DiffHighlight_HighlightToggle.Text = '✨ Show Highlight Differences';
                    app.DiffHighlight_HighlightToggle.BackgroundColor = [0.2157 0.4941 0.7216];
                end
                
                app.updateDiffHighlightStatus('Display reset completed');
                
            catch ME
                fprintf('Reset error: %s\n', ME.message);
            end
        end

    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: SelectInputFilesButton
        function SelectInputFilesButtonPushed(app, event)
            % Select folder and load image files
            imgTable = app.loadImageFolder();

            if height(imgTable) < 2, return; end
            
            app.addDataset(imgTable);

            app.displayDataset(imgTable);

            % Refocus app
            figure(app.UIFigure);
            
            % Start Preprocess in background
            app.preproccessImages();

            app.updateDataset();
        end

        % Value changed function: ImageSelectDropDown
        function ImageSelectDropDownValueChanged(app, event)
            value = app.ImageSelectDropDown.Value;
            if ~strcmp(app.PreviewImageData.Name, value)
                idx = find(strcmp(app.CurrentLoadedTable.Name, value), 1);
                if ~isempty(idx)
                    app.PreviewImageData = app.CurrentLoadedTable(idx, :);
                    app.updatePreview();
                end
            end
        end

        % Value changed function: PreviewModeDropDown
        function PreviewModeDropDownValueChanged(app, event)
            app.DashboardPreviewMode = app.PreviewModeDropDown.Value;
            app.updatePreview();
            app.updateStart();
            app.updateEnd();
        end

        % Selection changed function: FileTree
        function FileTreeSelectionChanged(app, event)
            selectedNodes = app.FileTree.SelectedNodes;
            if ~isa(selectedNodes.Parent, 'matlab.ui.container.CheckBoxTree')
                selectedNodesName = selectedNodes(1).Text;
                % Copy Pasta 
                if ~strcmp(app.PreviewImageData.Name, selectedNodesName)
                    idx = find(strcmp(app.CurrentLoadedTable.Name, selectedNodesName), 1);
                    if ~isempty(idx)
                        app.PreviewImageData = app.CurrentLoadedTable(idx, :);
                        app.updatePreview();
                    end
                end
            end
        end

        % Callback function: FileTree
        function FileTreeCheckedNodesChanged(app, event)
            checkedNodes = app.FileTree.CheckedNodes;
            currentDatasetName = app.getDatasetName(app.CurrentLoadedTable);
   
            % Filter for root-level node and exclude current dataset
            rootNodes = checkedNodes([checkedNodes.Parent] == app.FileTree);
            rootNodes = rootNodes(~strcmp({rootNodes.Text}, currentDatasetName));
        
            % If another dataset is checked, switch to it
            if ~isempty(rootNodes)
                datasetName = rootNodes(1).Text;
                idx = find(cellfun(@(d) strcmp(d.Name, datasetName), app.ImageDatasets), 1);
                if ~isempty(idx)
                    dataset = app.ImageDatasets{idx};
                    app.CurrentLoadedTable = dataset.Table;
                    app.CurrentLoadedTable.Properties.UserData = datasetName;
                    app.preproccessImages();
                    app.displayDataset(dataset.Table);
                    return;
                else
                    warning('Dataset "%s" not found in ImageDatasets.', datasetName);
                end
            else
                % No dataset switch — check changes in subnode selection
                parentNode = findobj(app.FileTree.Children, 'Text', currentDatasetName);
                if ~isempty(parentNode)
                    subNodes = checkedNodes([checkedNodes.Parent] == parentNode);
                    selectedNames = {subNodes.Text};
                    currentNames = app.CurrentLoadedTable.Name;
            
                    % Update only if selected images changed
                    if ~isequal(sort(selectedNames), sort(currentNames))
                        dataset = app.ImageDatasets{strcmp(currentDatasetName, cellfun(@(d) d.Name, app.ImageDatasets, 'UniformOutput', false))};
                        newTable = dataset.Table(ismember(dataset.Table.Name, selectedNames), :);
                        newTable.Properties.UserData = currentDatasetName;
                        app.CurrentLoadedTable = newTable;
                        app.CurrentLoadedTable.Properties.UserData = app.getDatasetName(newTable);
                        app.preproccessImages();
                        app.displayDataset(app.CurrentLoadedTable);
                    end
                end
            end
        end

        % Value changed function: StartImageDropDown
        function StartImageDropDownValueChanged(app, event)
            value = app.StartImageDropDown.Value;
            startIdx = find(strcmp(app.DashboardDropdownItems, value));

            % Limit End Image Dropdown to images at or after Start Image
            validEndItems = app.DashboardDropdownItems(startIdx+1:end);
            app.EndImageDropDown.Items = validEndItems;
            
            % Reset End Image selection if it was before the new start index
            if ~ismember(app.EndImageDropDown.Value, validEndItems)
                app.EndImageDropDown.Value = validEndItems(end);
            end
            app.updateDatasetFromSidebarSelection()
        end

        % Value changed function: EndImageDropDown
        function EndImageDropDownValueChanged(app, event)
            value = app.EndImageDropDown.Value;
            endIdx = find(strcmp(app.DashboardDropdownItems, value));

            % Limit First Image Dropdown to images at or after Start Image
            validStartItems = app.DashboardDropdownItems(1:endIdx-1);
            app.StartImageDropDown.Items = validStartItems;
            
            % Reset First Image selection if it was before the new end index
            if ~ismember(app.EndImageDropDown.Value, validStartItems)
                app.StartImageDropDown.Value = validStartItems(1); 
            end
            app.updateDatasetFromSidebarSelection()
        end

        % Value changed function: Slider_Img1DropDown
        function Slider_Img1DropDownValueChanged(app, event)
            value = app.Slider_Img1DropDown.Value;

            % Check if images are loaded
            if isempty(app.CurrentLoadedTable) || contains(value, 'Load images from Dashboard')
                app.Slider_SelectedImg1 = 0;
                app.Slider_StatusLabel.Text = '📂 Navigate to Dashboard → Select Input Files to load images';
                app.Slider_StatusLabel.FontColor = [0.7, 0.4, 0.1];
                return;
            end

            startIdx = find(strcmp(app.DashboardDropdownItems, value));

            % Limit End Image Dropdown to images at or after Start Image
            validEndItems = app.DashboardDropdownItems(startIdx+1:end);
            app.Slider_Img2DropDown.Items = validEndItems;
            
            % Reset End Image selection if it was before the new start index
            if ~ismember(app.Slider_Img2DropDown.Value, validEndItems)
                app.Slider_Img2DropDown.Value = validEndItems(end);
            end
            
            % Find the selected image index
            idx = find(strcmp(app.CurrentLoadedTable.Name, value), 1);
            if ~isempty(idx)
                app.Slider_SelectedImg1 = idx;
                app.initializeSliderImages();
                app.updateSliderDisplay();
                
                shortName = app.extractShortImageName(value);
                app.Slider_StatusLabel.Text = ['🖼️ Left: ' shortName ' • Ready to compare'];
                app.Slider_StatusLabel.FontColor = [0.2, 0.6, 0.2];
            else
                app.Slider_SelectedImg1 = 0;
                app.Slider_StatusLabel.Text = '❌ Left image selection failed';
                app.Slider_StatusLabel.FontColor = [0.8, 0, 0];
            end
            
        end

        % Value changed function: Slider_Img2DropDown
        function Slider_Img2DropDownValueChanged(app, event)
            value = app.Slider_Img2DropDown.Value;

            % Check if images are loaded
            if isempty(app.CurrentLoadedTable) || contains(value, 'Load images from Dashboard')
                app.Slider_SelectedImg2 = 0;
                app.Slider_StatusLabel.Text = '📂 Navigate to Dashboard → Select Input Files to load images';
                app.Slider_StatusLabel.FontColor = [0.7, 0.4, 0.1];
                return;
            end

            endIdx = find(strcmp(app.DashboardDropdownItems, value));

            % Limit First Image Dropdown to images at or after Start Image
            validStartItems = app.DashboardDropdownItems(1:endIdx-1);
            app.Slider_Img1DropDown.Items = validStartItems;
            
            % Reset First Image selection if it was before the new end index
            if ~ismember(app.EndImageDropDown.Value, validStartItems)
                app.Slider_Img1DropDown.Value = validStartItems(1); 
            end
            
            % Find the selected image index from globally loaded images
            idx = find(strcmp(app.CurrentLoadedTable.Name, value), 1);
            if ~isempty(idx)
                app.Slider_SelectedImg2 = idx;
                app.initializeSliderImages();
                app.updateSliderDisplay();
                
                % Modern status with emoji and clean text
                shortName = app.extractShortImageName(value);
                app.Slider_StatusLabel.Text = ['🖼️ Right: ' shortName ' • Ready to compare'];
                app.Slider_StatusLabel.FontColor = [0.2, 0.6, 0.2];
            else
                app.Slider_SelectedImg2 = 0;
                app.Slider_StatusLabel.Text = '❌ Right image selection failed';
                app.Slider_StatusLabel.FontColor = [0.8, 0, 0];
            end
            
        end

        % Button pushed function: Slider_ResetLeftButton
        function Slider_ResetLeftButtonPushed(app, event)
            app.resetSliderPosition('left');
        end

        % Button pushed function: Slider_ResetCenterButton
        function Slider_ResetCenterButtonPushed(app, event)
            app.resetSliderPosition('center');
        end

        % Button pushed function: Slider_ResetRightButton
        function Slider_ResetRightButtonPushed(app, event)
            app.resetSliderPosition('right');
        end

        % Button pushed function: Slider_AutoScanButton
        function Slider_AutoScanButtonPushed(app, event)
            app.performSliderAutoScan();
        end

        % Button pushed function: Slider_SaveGIFButton
        function Slider_SaveGIFButtonPushed(app, event)
            try
                if isempty(app.Slider_CurrentImg1) || isempty(app.Slider_CurrentImg2)
                    uialert(app.UIFigure, 'No images to create GIF. Please select images first.', 'Warning');
                    return;
                end
                
                % Open save dialog
                [file, path] = uiputfile('*.gif', 'Save Auto Scan GIF');
                if isequal(file, 0)
                    return; % User cancelled
                end
                
                [~, name, ~] = fileparts(file);
                filename = [name, '.gif'];
                fullPath = fullfile(path, filename);
                
                % Disable button during GIF creation
                app.Slider_SaveGIFButton.Enable = 'off';
                app.Slider_SaveGIFButton.Text = '⏳ Creating GIF...';
                app.Slider_StatusLabel.Text = 'Status: Creating auto scan GIF...';
                app.Slider_StatusLabel.FontColor = [0, 0.4, 0];
                
                % Create GIF
                app.createSliderAutoScanGIF(fullPath);
                
                % Re-enable button
                app.Slider_SaveGIFButton.Enable = 'on';
                app.Slider_SaveGIFButton.Text = '💾 Save Scan GIF';
                app.Slider_StatusLabel.Text = 'Status: GIF saved successfully';
                app.Slider_StatusLabel.FontColor = [0, 0.4, 0];
                
                msgbox(['Auto scan GIF saved successfully!' newline 'Location: ' fullPath], 'Success');
                
            catch ME
                app.Slider_SaveGIFButton.Enable = 'on';
                app.Slider_SaveGIFButton.Text = '💾 Save Scan GIF';
                app.Slider_StatusLabel.Text = ['Status: GIF creation error - ' ME.message];
                app.Slider_StatusLabel.FontColor = [0.8, 0, 0];
                uialert(app.UIFigure, ['GIF creation failed: ' ME.message], 'Error');
            end
        end

        % Value changing function: QuickSelectSlider
        function QuickSelectSliderValueChanging(app, event)
            selectedIdx = round(event.Value);
            selectedIdx = min(max(selectedIdx, 1), height(app.CurrentLoadedTable));

            currentIdx = find(strcmp(app.CurrentLoadedTable.Name, app.PreviewImageData.Name), 1);
            
            if isempty(currentIdx) || currentIdx ~= selectedIdx
                app.PreviewImageData = app.CurrentLoadedTable(selectedIdx, :);
                app.updatePreview();
            end
        end

        % Value changed function: DiffHighlight_Img1DropDown
        function DiffHighlight_Img1DropDownValueChanged(app, event)
            value = app.DiffHighlight_Img1DropDown.Value;
            idx = find(strcmp(app.CurrentLoadedTable.Name, value), 1);
            if ~isempty(idx)
                app.DiffHighlight_SelectedImg1 = idx;
                app.updateDifferenceHighlight();
                app.updateDiffHighlightAnalysis();
            end
        end

        % Value changed function: DiffHighlight_Img2DropDown
        function DiffHighlight_Img2DropDownValueChanged(app, event)
            value = app.DiffHighlight_Img2DropDown.Value;
            idx = find(strcmp(app.CurrentLoadedTable.Name, value), 1);
            if ~isempty(idx)
                app.DiffHighlight_SelectedImg2 = idx;
                app.updateDifferenceHighlight();
                app.updateDiffHighlightAnalysis();
            end
        end

        % Value changed function: DiffHighlight_ThresholdSlider
        function DiffHighlight_ThresholdSliderValueChanged(app, event)
            app.DiffHighlight_CurrentThreshold = app.DiffHighlight_ThresholdSlider.Value;
            app.DiffHighlight_ThresholdLabel.Text = sprintf('Threshold: %.2f', app.DiffHighlight_CurrentThreshold);
            app.updateDiffHighlightAnalysis();
        end

        % Button pushed function: DiffHighlight_IncreaseOnlyButton
        function DiffHighlight_IncreaseOnlyButtonPushed(app, event)
            app.showDiffHighlightIncreaseOnly();
        end

        % Button pushed function: DiffHighlight_DecreaseOnlyButton
        function DiffHighlight_DecreaseOnlyButtonPushed(app, event)
            app.showDiffHighlightDecreaseOnly();
        end

        % Button pushed function: DiffHighlight_ResetButton
        function DiffHighlight_ResetButtonPushed(app, event)
            app.resetDiffHighlightDisplay();
        end

        % Button pushed function: SaveAnalysisResultsButton
        function SaveAnalysisResultsButtonPushed(app, event)
            if isempty(get(app.DiffHighlight_Analysis_Axes, 'Children'))
                uialert(app.UIFigure, 'No results to save. Please run analysis first.', 'Warning');
                return;
            end
            
            try
                [filename, pathname] = uiputfile({...
                    '*.png', 'PNG Image (*.png)';...
                    '*.jpg', 'JPEG Image (*.jpg)';...
                    '*.fig', 'MATLAB Figure (*.fig)'}, ...
                    'Save Difference Highlight Analysis Results');
                
                if filename ~= 0
                    fullpath = fullfile(pathname, filename);
                    [~, ~, ext] = fileparts(filename);
                    
                    if strcmpi(ext, '.fig')
                        % Save the entire figure
                        tempFig = figure('Visible', 'off');
                        copyobj(app.DiffHighlight_Analysis_Axes, tempFig);
                        savefig(tempFig, fullpath);
                        close(tempFig);
                    else
                        % Export the analysis axes as image
                        exportgraphics(app.DiffHighlight_Analysis_Axes, fullpath, 'Resolution', 300);
                    end
                    
                    app.updateDiffHighlightStatus(sprintf('Results saved to: %s', filename));
                    uialert(app.UIFigure, 'Results saved successfully!', 'Success', 'Icon', 'success');
                end
                
            catch ME
                uialert(app.UIFigure, ['Save failed: ' ME.message], 'Error');
            end
        end

        % Value changed function: DiffHighlight_HighlightToggle
        function DiffHighlight_HighlightToggleValueChanged(app, event)
            if app.DiffHighlight_HighlightToggle.Value
                app.DiffHighlight_IsHighlightOn = true;
                app.DiffHighlight_IsIncreaseOnlyOn = false;
                app.DiffHighlight_IsDecreaseOnlyOn = false;
                app.DiffHighlight_HighlightToggle.Text = '✨ Hide Highlight Differences';
                app.DiffHighlight_HighlightToggle.BackgroundColor = [0.8 0.2 0.2];
                app.showDiffHighlightHighlight();
            else
                app.DiffHighlight_IsHighlightOn = false;
                app.DiffHighlight_HighlightToggle.Text = '✨ Show Highlight Differences';
                app.DiffHighlight_HighlightToggle.BackgroundColor = [0.2157 0.4941 0.7216];
                overlay = 0.5 * app.CroppedImages{app.DiffHighlight_SelectedImg1} + 0.5 * app.CroppedImages{app.DiffHighlight_SelectedImg2};
                imshow(overlay, 'Parent', app.DiffHighlight_Analysis_Axes);
                title(app.DiffHighlight_Analysis_Axes, 'Difference Highlight Overlay Image', 'FontSize', 10, 'FontWeight', 'bold');
                app.DiffHighlight_CurrentHighlightImg = [];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout11 = uigridlayout(app.Panel);
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.Image = uiimage(app.GridLayout12);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'gui', 'img', 'title.svg');
            app.GridLayout13 = uigridlayout(app.GridLayout11);
            app.TabGroup2 = uitabgroup(app.GridLayout13);
            app.OverviewTab = uitab(app.TabGroup2);
            app.OverviewTab.Title = 'Overview';
            app.GridLayout14 = uigridlayout(app.OverviewTab);
            app.SelectInputFilesButton = uibutton(app.GridLayout14, 'push');
            app.SelectInputFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInputFilesButtonPushed, true);
            app.SelectInputFilesButton.Icon = fullfile(pathToMLAPP, 'gui', 'img', 'icon_files.svg');
            app.SelectInputFilesButton.Text = 'Select Input Files';
            app.Step1Label = uilabel(app.GridLayout14);
            app.Step1Label.Text = 'Step 1';
            app.Step2Label = uilabel(app.GridLayout14);
            app.Step2Label.Text = 'Step 2';
            app.GridLayout21 = uigridlayout(app.GridLayout14);
            app.Label = uilabel(app.GridLayout21);
            app.Label.Text = '';
            app.StartImageDropDown = uidropdown(app.GridLayout21);
            app.StartImageDropDown.Items = {};
            app.StartImageDropDown.ValueChangedFcn = createCallbackFcn(app, @StartImageDropDownValueChanged, true);
            app.StartImageDropDown.Placeholder = 'Select First / Start Image';
            app.StartImageDropDown.Value = {};
            app.DropDown_2Label = uilabel(app.GridLayout21);
            app.DropDown_2Label.Text = '';
            app.EndImageDropDown = uidropdown(app.GridLayout21);
            app.EndImageDropDown.Items = {};
            app.EndImageDropDown.ValueChangedFcn = createCallbackFcn(app, @EndImageDropDownValueChanged, true);
            app.EndImageDropDown.Placeholder = 'Select Second / End Image';
            app.EndImageDropDown.Value = {};
            app.FilesTab_2 = uitab(app.TabGroup2);
            app.FilesTab_2.Title = 'Files';
            app.GridLayout15 = uigridlayout(app.FilesTab_2);
            app.LoadedFilesLabel = uilabel(app.GridLayout15);
            app.LoadedFilesLabel.Text = 'Loaded Files: ';
            app.FileTree = uitree(app.GridLayout15, 'checkbox');
            app.FileTree.SelectionChangedFcn = createCallbackFcn(app, @FileTreeSelectionChanged, true);
            app.NoFilesLoadedNode = uitreenode(app.FileTree);
            app.NoFilesLoadedNode.Text = 'No Files Loaded';
            % Assign Checked Nodes
            app.FileTree.CheckedNodesChangedFcn = createCallbackFcn(app, @FileTreeCheckedNodesChanged, true);
            app.SettingsTab = uitab(app.TabGroup2);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout14_2 = uigridlayout(app.SettingsTab);
            app.SettingsLabel = uilabel(app.GridLayout14_2);
            app.SettingsLabel.Text = 'Settings';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.EarthViewerGUITeam25Label = uilabel(app.GridLayout3);
            app.EarthViewerGUITeam25Label.Text = 'EarthViewer GUI Team 25';
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.DashboardTab = uitab(app.TabGroup);
            app.DashboardTab.Title = 'Dashboard';
            app.GridLayout4 = uigridlayout(app.DashboardTab);
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.ImageLoadedLabel = uilabel(app.GridLayout7);
            app.ImageLoadedLabel.Text = 'Image Loaded';
            app.ImagesLabel = uilabel(app.GridLayout7);
            app.ImagesLabel.Text = '-- Images';
            app.GridLayout7_2 = uigridlayout(app.GridLayout6);
            app.TimespanLabel = uilabel(app.GridLayout7_2);
            app.TimespanLabel.Text = 'Timespan';
            app.YearsLabel = uilabel(app.GridLayout7_2);
            app.YearsLabel.Text = '-- Years';
            app.GridLayout7_3 = uigridlayout(app.GridLayout6);
            app.TimeBetweenImagesLabel = uilabel(app.GridLayout7_3);
            app.TimeBetweenImagesLabel.Text = 'Time Between Images';
            app.MonthLabel = uilabel(app.GridLayout7_3);
            app.MonthLabel.Text = '-- Month';
            app.GridLayout7_4 = uigridlayout(app.GridLayout6);
            app.CroppedImageSizeLabel = uilabel(app.GridLayout7_4);
            app.CroppedImageSizeLabel.Text = 'Cropped Image Size';
            app.ImageSizeLabel = uilabel(app.GridLayout7_4);
            app.ImageSizeLabel.Text = '---- x ----';
            app.GridLayout8 = uigridlayout(app.GridLayout4);
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.Panel_3 = uipanel(app.GridLayout9);
            app.GridLayout16_2 = uigridlayout(app.Panel_3);
            app.ImageSetInformationLabel = uilabel(app.GridLayout16_2);
            app.ImageSetInformationLabel.Text = 'Image Set Information';
            app.GridLayout22 = uigridlayout(app.GridLayout16_2);
            app.ImageSetNameTagLabel = uilabel(app.GridLayout22);
            app.ImageSetNameTagLabel.Text = 'Image Set Name:';
            app.ImageSetNameLabel = uilabel(app.GridLayout22);
            app.ImageSetNameLabel.Text = 'No Files Loaded';
            app.Panel_4 = uipanel(app.GridLayout9);
            app.GridLayout16_3 = uigridlayout(app.Panel_4);
            app.ImagePreviewSettingLabel = uilabel(app.GridLayout16_3);
            app.ImagePreviewSettingLabel.Text = 'Image Preview Setting';
            app.GridLayout18 = uigridlayout(app.GridLayout16_3);
            app.ImageSelectDropDownLabel = uilabel(app.GridLayout18);
            app.ImageSelectDropDownLabel.Text = 'Image Select';
            app.ImageSelectDropDown = uidropdown(app.GridLayout18);
            app.ImageSelectDropDown.Items = {};
            app.ImageSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageSelectDropDownValueChanged, true);
            app.ImageSelectDropDown.Placeholder = 'None';
            app.ImageSelectDropDown.Value = {};
            app.PreviewModeDropDownLabel = uilabel(app.GridLayout18);
            app.PreviewModeDropDownLabel.Text = 'Preview Mode';
            app.PreviewModeDropDown = uidropdown(app.GridLayout18);
            app.PreviewModeDropDown.Items = {'Original', 'Aligned', 'Cropped'};
            app.PreviewModeDropDown.ValueChangedFcn = createCallbackFcn(app, @PreviewModeDropDownValueChanged, true);
            app.PreviewModeDropDown.Value = 'Original';
            app.QuickSelectSliderLabel = uilabel(app.GridLayout18);
            app.QuickSelectSliderLabel.Text = 'Quick Select';
            app.QuickSelectSlider = uislider(app.GridLayout18);
            app.QuickSelectSlider.ValueChangingFcn = createCallbackFcn(app, @QuickSelectSliderValueChanging, true);
            app.Panel_2 = uipanel(app.GridLayout8);
            app.GridLayout16 = uigridlayout(app.Panel_2);
            app.GridLayoutImagePreview = uigridlayout(app.GridLayout16);
            app.UIAxesImagePreview = uiaxes(app.GridLayoutImagePreview);
            app.UIAxesImagePreview.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.GridLayout19 = uigridlayout(app.GridLayout16);
            app.ImagePreviewLabel = uilabel(app.GridLayout19);
            app.ImagePreviewLabel.Text = 'Image Preview';
            app.PreviewFilenameLabel = uilabel(app.GridLayout19);
            app.PreviewFilenameLabel.Text = ' ';
            app.GridLayout10 = uigridlayout(app.GridLayout4);
            app.Panel_5 = uipanel(app.GridLayout10);
            app.GridLayout16_4 = uigridlayout(app.Panel_5);
            app.GridLayoutStartImage = uigridlayout(app.GridLayout16_4);
            app.GridLayout20 = uigridlayout(app.GridLayout16_4);
            app.StartImageLabel = uilabel(app.GridLayout20);
            app.StartImageLabel.Text = 'Start Image';
            app.StartImgFilenameLabel = uilabel(app.GridLayout20);
            app.StartImgFilenameLabel.Text = ' ';
            app.Panel_6 = uipanel(app.GridLayout10);
            app.GridLayout16_5 = uigridlayout(app.Panel_6);
            app.GridLayoutEndImage = uigridlayout(app.GridLayout16_5);
            app.GridLayout20_2 = uigridlayout(app.GridLayout16_5);
            app.EndImageLabel = uilabel(app.GridLayout20_2);
            app.EndImageLabel.Text = 'End Image';
            app.EndImgFilenameLabel = uilabel(app.GridLayout20_2);
            app.EndImgFilenameLabel.Text = ' ';
            app.Panel_7 = uipanel(app.GridLayout10);
            app.GridLayout16_6 = uigridlayout(app.Panel_7);
            app.GeologicalLocationLabel = uilabel(app.GridLayout16_6);
            app.GeologicalLocationLabel.Text = 'Geological Location';
            app.SliderViewTab = uitab(app.TabGroup);
            app.SliderViewTab.Title = 'Slider View';
            app.GridLayout4_2 = uigridlayout(app.SliderViewTab);
            app.SliderTitleBar = uipanel(app.GridLayout4_2);
            app.SliderTitleLayout = uigridlayout(app.SliderTitleBar);
            app.ClickanddragtocontrolcurtainpositionLabel = uilabel(app.SliderTitleLayout);
            app.ClickanddragtocontrolcurtainpositionLabel.Text = 'Click and drag to control curtain position';
            app.SliderMainTitle = uilabel(app.SliderTitleLayout);
            app.SliderMainTitle.Text = '🎚️ Interactive Curtain Slider';
            app.GridLayout8_2 = uigridlayout(app.GridLayout4_2);
            app.Panel_39 = uipanel(app.GridLayout8_2);
            app.SliderMainLayout = uigridlayout(app.Panel_39);
            app.Slider_MainAxes = uiaxes(app.SliderMainLayout);
            app.Slider_MainAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Slider_StatusLabel = uilabel(app.SliderMainLayout);
            app.Slider_StatusLabel.Text = '✅ Ready for comparison - Load images from Dashboard';
            app.Panel_40 = uipanel(app.GridLayout8_2);
            app.GridLayout23 = uigridlayout(app.Panel_40);
            app.SliderControlsLabel = uilabel(app.GridLayout23);
            app.SliderControlsLabel.Text = '⚙️ Slider Controls';
            app.GridLayout24 = uigridlayout(app.GridLayout23);
            app.Label_3 = uilabel(app.GridLayout24);
            app.Label_3.Text = '';
            app.Slider_Img1DropDown = uidropdown(app.GridLayout24);
            app.Slider_Img1DropDown.Items = {''};
            app.Slider_Img1DropDown.ValueChangedFcn = createCallbackFcn(app, @Slider_Img1DropDownValueChanged, true);
            app.Slider_Img1DropDown.Placeholder = 'Select Left Image';
            app.Slider_Img1DropDown.Value = '';
            app.DropDown_2Label_2 = uilabel(app.GridLayout24);
            app.DropDown_2Label_2.Text = '';
            app.Slider_Img2DropDown = uidropdown(app.GridLayout24);
            app.Slider_Img2DropDown.Items = {};
            app.Slider_Img2DropDown.ValueChangedFcn = createCallbackFcn(app, @Slider_Img2DropDownValueChanged, true);
            app.Slider_Img2DropDown.Placeholder = 'Select Right Image';
            app.Slider_Img2DropDown.Value = {};
            app.GridLayout25 = uigridlayout(app.GridLayout23);
            app.Panel_41 = uipanel(app.GridLayout25);
            app.GridLayout25_2 = uigridlayout(app.GridLayout23);
            app.Panel_42 = uipanel(app.GridLayout25_2);
            app.GridLayout25_3 = uigridlayout(app.GridLayout23);
            app.Panel_43 = uipanel(app.GridLayout25_3);
            app.SliderPositionCardLayout = uigridlayout(app.GridLayout23);
            app.Slider_ResetLeftButton = uibutton(app.SliderPositionCardLayout, 'push');
            app.Slider_ResetLeftButton.ButtonPushedFcn = createCallbackFcn(app, @Slider_ResetLeftButtonPushed, true);
            app.Slider_ResetLeftButton.Text = '◀ Show Left';
            app.Slider_ResetCenterButton = uibutton(app.SliderPositionCardLayout, 'push');
            app.Slider_ResetCenterButton.ButtonPushedFcn = createCallbackFcn(app, @Slider_ResetCenterButtonPushed, true);
            app.Slider_ResetCenterButton.Text = '⚌ Center';
            app.Slider_ResetRightButton = uibutton(app.SliderPositionCardLayout, 'push');
            app.Slider_ResetRightButton.ButtonPushedFcn = createCallbackFcn(app, @Slider_ResetRightButtonPushed, true);
            app.Slider_ResetRightButton.Text = 'Show Right ▶';
            app.GridLayout27 = uigridlayout(app.GridLayout23);
            app.Slider_AutoScanButton = uibutton(app.GridLayout27, 'push');
            app.Slider_AutoScanButton.ButtonPushedFcn = createCallbackFcn(app, @Slider_AutoScanButtonPushed, true);
            app.Slider_AutoScanButton.Text = '🔄 Auto Scan';
            app.Slider_SaveGIFButton = uibutton(app.GridLayout27, 'push');
            app.Slider_SaveGIFButton.ButtonPushedFcn = createCallbackFcn(app, @Slider_SaveGIFButtonPushed, true);
            app.Slider_SaveGIFButton.Text = '💾 Export as GIF';
            app.SliderInstructionCard = uilabel(app.GridLayout27);
            app.SliderInstructionCard.Text = '💡 Interactive Controls: Click & drag image to move curtain';
            app.DifferenceHighlightTab = uitab(app.TabGroup);
            app.DifferenceHighlightTab.Title = 'Difference Highlight';
            app.GridLayout4_3 = uigridlayout(app.DifferenceHighlightTab);
            app.GridLayout4_8 = uigridlayout(app.GridLayout4_3);
            app.Panel_44 = uipanel(app.GridLayout4_8);
            app.DiffHighlightMainLayout = uigridlayout(app.Panel_44);
            app.DiffHighlight_Analysis_Axes = uiaxes(app.DiffHighlightMainLayout);
            title(app.DiffHighlight_Analysis_Axes, '🔍 Difference Highlight Analysis Results')
            app.DiffHighlight_Analysis_Axes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Panel_45 = uipanel(app.GridLayout4_8);
            app.GridLayout28 = uigridlayout(app.Panel_45);
            app.DiffHighlight_StatusLabel = uilabel(app.GridLayout28);
            app.DiffHighlight_StatusLabel.Text = 'Status: Ready for analysis';
            app.imgSelectionLabel = uilabel(app.GridLayout28);
            app.imgSelectionLabel.Text = '📷 Image Selection';
            app.GridLayout29 = uigridlayout(app.GridLayout28);
            app.Label_5 = uilabel(app.GridLayout29);
            app.Label_5.Text = '';
            app.DiffHighlight_Img2DropDown = uidropdown(app.GridLayout29);
            app.DiffHighlight_Img2DropDown.Items = {''};
            app.DiffHighlight_Img2DropDown.ValueChangedFcn = createCallbackFcn(app, @DiffHighlight_Img2DropDownValueChanged, true);
            app.DiffHighlight_Img2DropDown.Placeholder = 'Select Left Image';
            app.DiffHighlight_Img2DropDown.Value = '';
            app.Label_4 = uilabel(app.GridLayout29);
            app.Label_4.Text = '';
            app.DiffHighlight_Img1DropDown = uidropdown(app.GridLayout29);
            app.DiffHighlight_Img1DropDown.Items = {''};
            app.DiffHighlight_Img1DropDown.ValueChangedFcn = createCallbackFcn(app, @DiffHighlight_Img1DropDownValueChanged, true);
            app.DiffHighlight_Img1DropDown.Placeholder = 'Select Left Image';
            app.DiffHighlight_Img1DropDown.Value = '';
            app.thresholdLabel = uilabel(app.GridLayout28);
            app.thresholdLabel.Text = '⚙️ Threshold Settings';
            app.DiffHighlight_ThresholdSlider = uislider(app.GridLayout28);
            app.DiffHighlight_ThresholdSlider.Limits = [0 1];
            app.DiffHighlight_ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @DiffHighlight_ThresholdSliderValueChanged, true);
            app.DiffHighlight_ThresholdSlider.Value = 0.1;
            app.DiffHighlight_ThresholdLabel = uilabel(app.GridLayout28);
            app.DiffHighlight_ThresholdLabel.Text = 'Threshold: 0.10';
            app.analysisFuncLabel = uilabel(app.GridLayout28);
            app.analysisFuncLabel.Text = '🔬Analysis Functions';
            app.DiffHighlight_HighlightToggle = uibutton(app.GridLayout28, 'state');
            app.DiffHighlight_HighlightToggle.ValueChangedFcn = createCallbackFcn(app, @DiffHighlight_HighlightToggleValueChanged, true);
            app.DiffHighlight_HighlightToggle.Text = '✨ Show Highlight Differences';
            app.DiffDetailButtonLayout = uigridlayout(app.GridLayout28);
            app.DiffHighlight_DecreaseOnlyButton = uibutton(app.DiffDetailButtonLayout, 'push');
            app.DiffHighlight_DecreaseOnlyButton.ButtonPushedFcn = createCallbackFcn(app, @DiffHighlight_DecreaseOnlyButtonPushed, true);
            app.DiffHighlight_DecreaseOnlyButton.Text = '📉 Decrease Only';
            app.DiffHighlight_IncreaseOnlyButton = uibutton(app.DiffDetailButtonLayout, 'push');
            app.DiffHighlight_IncreaseOnlyButton.ButtonPushedFcn = createCallbackFcn(app, @DiffHighlight_IncreaseOnlyButtonPushed, true);
            app.DiffHighlight_IncreaseOnlyButton.Text = '📈 Increase Only';
            app.GridLayout10_8 = uigridlayout(app.GridLayout4_3);
            app.Panel_46 = uipanel(app.GridLayout10_8);
            app.GridLayout16_7 = uigridlayout(app.Panel_46);
            app.DiffHighlight_LeftImage_Grid = uigridlayout(app.GridLayout16_7);
            app.GridLayout20_3 = uigridlayout(app.GridLayout16_7);
            app.FirstImageLabel = uilabel(app.GridLayout20_3);
            app.FirstImageLabel.Text = 'First Image';
            app.DiffHighlight_LeftImage_Label = uilabel(app.GridLayout20_3);
            app.DiffHighlight_LeftImage_Label.Text = ' ';
            app.Panel_47 = uipanel(app.GridLayout10_8);
            app.GridLayout16_8 = uigridlayout(app.Panel_47);
            app.DiffHighlight_RightImage_Grid = uigridlayout(app.GridLayout16_8);
            app.GridLayout20_4 = uigridlayout(app.GridLayout16_8);
            app.SecondImageLabel = uilabel(app.GridLayout20_4);
            app.SecondImageLabel.Text = 'Second Image';
            app.DiffHighlight_RightImage_Label = uilabel(app.GridLayout20_4);
            app.DiffHighlight_RightImage_Label.Text = ' ';
            app.Panel_48 = uipanel(app.GridLayout10_8);
            app.GridLayout16_9 = uigridlayout(app.Panel_48);
            app.OperationToolsLabel = uilabel(app.GridLayout16_9);
            app.OperationToolsLabel.Text = 'Operation Tools';
            app.GridLayout32 = uigridlayout(app.GridLayout16_9);
            app.SaveAnalysisResultsButton = uibutton(app.GridLayout32, 'push');
            app.SaveAnalysisResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnalysisResultsButtonPushed, true);
            app.SaveAnalysisResultsButton.Text = '💾 Save Analysis Results';
            app.DiffHighlight_ResetButton = uibutton(app.GridLayout32, 'push');
            app.DiffHighlight_ResetButton.ButtonPushedFcn = createCallbackFcn(app, @DiffHighlight_ResetButtonPushed, true);
            app.DiffHighlight_ResetButton.Text = '🔄 Reset';
            app.HeatmapTab = uitab(app.TabGroup);
            app.HeatmapTab.Title = 'Heatmap';
            app.GridLayout4_11 = uigridlayout(app.HeatmapTab);
            app.GridLayout4_12 = uigridlayout(app.GridLayout4_11);
            app.Panel_54 = uipanel(app.GridLayout4_12);
            app.DiffHighlightMainLayout_3 = uigridlayout(app.Panel_54);
            app.DiffHighlight_Analysis_Axes_3 = uiaxes(app.DiffHighlightMainLayout_3);
            title(app.DiffHighlight_Analysis_Axes_3, '🔍 Difference Highlight Analysis Results')
            app.DiffHighlight_Analysis_Axes_3.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Panel_55 = uipanel(app.GridLayout4_12);
            app.GridLayout28_3 = uigridlayout(app.Panel_55);
            app.DiffHighlight_StatusLabel_3 = uilabel(app.GridLayout28_3);
            app.DiffHighlight_StatusLabel_3.Text = 'Status: Ready for analysis';
            app.imgSelectionLabel_3 = uilabel(app.GridLayout28_3);
            app.imgSelectionLabel_3.Text = '📷 Image Selection';
            app.GridLayout29_3 = uigridlayout(app.GridLayout28_3);
            app.Label_8 = uilabel(app.GridLayout29_3);
            app.Label_8.Text = '';
            app.DiffHighlight_Img2DropDown_3 = uidropdown(app.GridLayout29_3);
            app.DiffHighlight_Img2DropDown_3.Items = {''};
            app.DiffHighlight_Img2DropDown_3.Placeholder = 'Select Left Image';
            app.DiffHighlight_Img2DropDown_3.Value = '';
            app.Label_9 = uilabel(app.GridLayout29_3);
            app.Label_9.Text = '';
            app.DiffHighlight_Img1DropDown_3 = uidropdown(app.GridLayout29_3);
            app.DiffHighlight_Img1DropDown_3.Items = {''};
            app.DiffHighlight_Img1DropDown_3.Placeholder = 'Select Left Image';
            app.DiffHighlight_Img1DropDown_3.Value = '';
            app.thresholdLabel_3 = uilabel(app.GridLayout28_3);
            app.thresholdLabel_3.Text = '⚙️ Threshold Settings';
            app.DiffHighlight_ThresholdSlider_3 = uislider(app.GridLayout28_3);
            app.DiffHighlight_ThresholdSlider_3.Value = 70;
            app.DiffHighlight_ThresholdLabel_3 = uilabel(app.GridLayout28_3);
            app.DiffHighlight_ThresholdLabel_3.Text = 'Threshold: 0.10';
            app.analysisFuncLabel_3 = uilabel(app.GridLayout28_3);
            app.analysisFuncLabel_3.Text = '🔬Analysis Functions';
            app.DiffHighlight_HighlightToggle_3 = uibutton(app.GridLayout28_3, 'push');
            app.DiffHighlight_HighlightToggle_3.Text = '✨ Show Highlight Differences';
            app.DiffDetailButtonLayout_3 = uigridlayout(app.GridLayout28_3);
            app.DiffHighlight_DecreaseOnlyButton_3 = uibutton(app.DiffDetailButtonLayout_3, 'push');
            app.DiffHighlight_DecreaseOnlyButton_3.Text = '📉 Decrease Only';
            app.DiffHighlight_IncreaseOnlyButton_3 = uibutton(app.DiffDetailButtonLayout_3, 'push');
            app.DiffHighlight_IncreaseOnlyButton_3.Text = '📈 Increase Only';
            app.GridLayout10_10 = uigridlayout(app.GridLayout4_11);
            app.Panel_56 = uipanel(app.GridLayout10_10);
            app.GridLayout16_13 = uigridlayout(app.Panel_56);
            app.DiffHighlight_LeftImage_Cropped_3 = uigridlayout(app.GridLayout16_13);
            app.GridLayout20_7 = uigridlayout(app.GridLayout16_13);
            app.FirstImageLabel_3 = uilabel(app.GridLayout20_7);
            app.FirstImageLabel_3.Text = 'First Image';
            app.StartImgFilenameLabel_4 = uilabel(app.GridLayout20_7);
            app.StartImgFilenameLabel_4.Text = ' ';
            app.Panel_57 = uipanel(app.GridLayout10_10);
            app.GridLayout16_14 = uigridlayout(app.Panel_57);
            app.DiffHighlight_RightImage_Cropped_3 = uigridlayout(app.GridLayout16_14);
            app.GridLayout20_8 = uigridlayout(app.GridLayout16_14);
            app.SecondImageLabel_3 = uilabel(app.GridLayout20_8);
            app.SecondImageLabel_3.Text = 'Second Image';
            app.EndImgFilenameLabel_4 = uilabel(app.GridLayout20_8);
            app.EndImgFilenameLabel_4.Text = ' ';
            app.Panel_58 = uipanel(app.GridLayout10_10);
            app.GridLayout16_15 = uigridlayout(app.Panel_58);
            app.OperationToolsLabel_3 = uilabel(app.GridLayout16_15);
            app.OperationToolsLabel_3.Text = 'Operation Tools';
            app.GridLayout32_3 = uigridlayout(app.GridLayout16_15);
            app.SaveAnalysisResultsButton_3 = uibutton(app.GridLayout32_3, 'push');
            app.SaveAnalysisResultsButton_3.Text = '💾 Save Analysis Results';
            app.DiffHighlight_ResetButton_3 = uibutton(app.GridLayout32_3, 'push');
            app.DiffHighlight_ResetButton_3.Text = '🔄 Reset';
            app.TimelapseTab = uitab(app.TabGroup);
            app.TimelapseTab.Title = 'Timelapse';
            app.GridLayout4_9 = uigridlayout(app.TimelapseTab);
            app.GridLayout4_10 = uigridlayout(app.GridLayout4_9);
            app.Panel_49 = uipanel(app.GridLayout4_10);
            app.DiffHighlightMainLayout_2 = uigridlayout(app.Panel_49);
            app.DiffHighlight_Analysis_Axes_2 = uiaxes(app.DiffHighlightMainLayout_2);
            title(app.DiffHighlight_Analysis_Axes_2, '🔍 Difference Highlight Analysis Results')
            app.DiffHighlight_Analysis_Axes_2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Panel_50 = uipanel(app.GridLayout4_10);
            app.GridLayout28_2 = uigridlayout(app.Panel_50);
            app.DiffHighlight_StatusLabel_2 = uilabel(app.GridLayout28_2);
            app.DiffHighlight_StatusLabel_2.Text = 'Status: Ready for analysis';
            app.imgSelectionLabel_2 = uilabel(app.GridLayout28_2);
            app.imgSelectionLabel_2.Text = '📷 Image Selection';
            app.GridLayout29_2 = uigridlayout(app.GridLayout28_2);
            app.Label_6 = uilabel(app.GridLayout29_2);
            app.Label_6.Text = '';
            app.DiffHighlight_Img2DropDown_2 = uidropdown(app.GridLayout29_2);
            app.DiffHighlight_Img2DropDown_2.Items = {''};
            app.DiffHighlight_Img2DropDown_2.Placeholder = 'Select Left Image';
            app.DiffHighlight_Img2DropDown_2.Value = '';
            app.Label_7 = uilabel(app.GridLayout29_2);
            app.Label_7.Text = '';
            app.DiffHighlight_Img1DropDown_2 = uidropdown(app.GridLayout29_2);
            app.DiffHighlight_Img1DropDown_2.Items = {''};
            app.DiffHighlight_Img1DropDown_2.Placeholder = 'Select Left Image';
            app.DiffHighlight_Img1DropDown_2.Value = '';
            app.thresholdLabel_2 = uilabel(app.GridLayout28_2);
            app.thresholdLabel_2.Text = '⚙️ Threshold Settings';
            app.DiffHighlight_ThresholdSlider_2 = uislider(app.GridLayout28_2);
            app.DiffHighlight_ThresholdSlider_2.Value = 70;
            app.DiffHighlight_ThresholdLabel_2 = uilabel(app.GridLayout28_2);
            app.DiffHighlight_ThresholdLabel_2.Text = 'Threshold: 0.10';
            app.analysisFuncLabel_2 = uilabel(app.GridLayout28_2);
            app.analysisFuncLabel_2.Text = '🔬Analysis Functions';
            app.DiffHighlight_HighlightToggle_2 = uibutton(app.GridLayout28_2, 'push');
            app.DiffHighlight_HighlightToggle_2.Text = '✨ Show Highlight Differences';
            app.DiffDetailButtonLayout_2 = uigridlayout(app.GridLayout28_2);
            app.DiffHighlight_DecreaseOnlyButton_2 = uibutton(app.DiffDetailButtonLayout_2, 'push');
            app.DiffHighlight_DecreaseOnlyButton_2.Text = '📉 Decrease Only';
            app.DiffHighlight_IncreaseOnlyButton_2 = uibutton(app.DiffDetailButtonLayout_2, 'push');
            app.DiffHighlight_IncreaseOnlyButton_2.Text = '📈 Increase Only';
            app.GridLayout10_9 = uigridlayout(app.GridLayout4_9);
            app.Panel_51 = uipanel(app.GridLayout10_9);
            app.GridLayout16_10 = uigridlayout(app.Panel_51);
            app.DiffHighlight_LeftImage_Cropped_2 = uigridlayout(app.GridLayout16_10);
            app.GridLayout20_5 = uigridlayout(app.GridLayout16_10);
            app.FirstImageLabel_2 = uilabel(app.GridLayout20_5);
            app.FirstImageLabel_2.Text = 'First Image';
            app.StartImgFilenameLabel_3 = uilabel(app.GridLayout20_5);
            app.StartImgFilenameLabel_3.Text = ' ';
            app.Panel_52 = uipanel(app.GridLayout10_9);
            app.GridLayout16_11 = uigridlayout(app.Panel_52);
            app.DiffHighlight_RightImage_Cropped_2 = uigridlayout(app.GridLayout16_11);
            app.GridLayout20_6 = uigridlayout(app.GridLayout16_11);
            app.SecondImageLabel_2 = uilabel(app.GridLayout20_6);
            app.SecondImageLabel_2.Text = 'Second Image';
            app.EndImgFilenameLabel_3 = uilabel(app.GridLayout20_6);
            app.EndImgFilenameLabel_3.Text = ' ';
            app.Panel_53 = uipanel(app.GridLayout10_9);
            app.GridLayout16_12 = uigridlayout(app.Panel_53);
            app.OperationToolsLabel_2 = uilabel(app.GridLayout16_12);
            app.OperationToolsLabel_2.Text = 'Operation Tools';
            app.GridLayout32_2 = uigridlayout(app.GridLayout16_12);
            app.SaveAnalysisResultsButton_2 = uibutton(app.GridLayout32_2, 'push');
            app.SaveAnalysisResultsButton_2.Text = '💾 Save Analysis Results';
            app.DiffHighlight_ResetButton_2 = uibutton(app.GridLayout32_2, 'push');
            app.DiffHighlight_ResetButton_2.Text = '🔄 Reset';
            app.FlickerViewTab = uitab(app.TabGroup);
            app.FlickerViewTab.Title = 'Flicker View';
            app.GridLayout4_5 = uigridlayout(app.FlickerViewTab);
            app.GridLayout8_5 = uigridlayout(app.GridLayout4_5);
            app.GridLayout9_5 = uigridlayout(app.GridLayout8_5);
            app.Panel_28 = uipanel(app.GridLayout9_5);
            app.Panel_27 = uipanel(app.GridLayout8_5);
            app.GridLayout10_5 = uigridlayout(app.GridLayout4_5);
            app.Panel_29 = uipanel(app.GridLayout10_5);
            app.Panel_30 = uipanel(app.GridLayout10_5);
            app.SemanticSegmentationTab = uitab(app.TabGroup);
            app.SemanticSegmentationTab.Title = 'Semantic Segmentation';
            app.GridLayout4_6 = uigridlayout(app.SemanticSegmentationTab);
            app.GridLayout8_6 = uigridlayout(app.GridLayout4_6);
            app.GridLayout9_6 = uigridlayout(app.GridLayout8_6);
            app.Panel_32 = uipanel(app.GridLayout9_6);
            app.Panel_31 = uipanel(app.GridLayout8_6);
            app.GridLayout10_6 = uigridlayout(app.GridLayout4_6);
            app.Panel_33 = uipanel(app.GridLayout10_6);
            app.Panel_34 = uipanel(app.GridLayout10_6);
            app.ReportTab = uitab(app.TabGroup);
            app.ReportTab.Title = 'Report';
            app.GridLayout4_7 = uigridlayout(app.ReportTab);
            app.GridLayout8_7 = uigridlayout(app.GridLayout4_7);
            app.GridLayout9_7 = uigridlayout(app.GridLayout8_7);
            app.Panel_36 = uipanel(app.GridLayout9_7);
            app.Panel_35 = uipanel(app.GridLayout8_7);
            app.GridLayout10_7 = uigridlayout(app.GridLayout4_7);
            app.Panel_37 = uipanel(app.GridLayout10_7);
            app.Panel_38 = uipanel(app.GridLayout10_7);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cv_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end